classdef AArmForceSolver < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        REARLOWERFOREDropDown           matlab.ui.control.DropDown
        REARLOWERFOREDropDownLabel      matlab.ui.control.Label
        REARUPPERFOREDropDown           matlab.ui.control.DropDown
        REARUPPERFOREDropDownLabel      matlab.ui.control.Label
        REARUPPERAFTDropDown            matlab.ui.control.DropDown
        REARUPPERAFTDropDownLabel       matlab.ui.control.Label
        REARLOWERAFTDropDown            matlab.ui.control.DropDown
        REARLOWERAFTDropDownLabel       matlab.ui.control.Label
        REARPUSHRODDropDown             matlab.ui.control.DropDown
        REARPUSHRODDropDownLabel        matlab.ui.control.Label
        REARTIERODDropDown              matlab.ui.control.DropDown
        REARTIERODDropDownLabel         matlab.ui.control.Label
        FRONTLOWERAFTDropDown           matlab.ui.control.DropDown
        FRONTLOWERAFTDropDownLabel      matlab.ui.control.Label
        FRONTTIERODDropDown             matlab.ui.control.DropDown
        FRONTTIERODDropDownLabel        matlab.ui.control.Label
        FRONTPUSHRODDropDown            matlab.ui.control.DropDown
        FRONTPUSHRODDropDownLabel       matlab.ui.control.Label
        FRONTLOWERFOREDropDown          matlab.ui.control.DropDown
        FRONTLOWERFOREDropDownLabel     matlab.ui.control.Label
        FRONTUPPERAFTDropDown           matlab.ui.control.DropDown
        FRONTUPPERAFTDropDownLabel      matlab.ui.control.Label
        FRONTUPPERFOREDropDown          matlab.ui.control.DropDown
        FRONTUPPERFOREDropDownLabel     matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        InputAArmCoordsSAESYSTEMTab     matlab.ui.container.Tab
        ENTERLEFTSIDEVEHICLECOORDSNEGATIVEYCOORDSLabel  matlab.ui.control.Label
        FRONTINBOARDCOORDSMMLabel       matlab.ui.control.Label
        FRONTOUTBOARDCOORDSMMLabel      matlab.ui.control.Label
        REARINBOARDCOORDSMMLabel        matlab.ui.control.Label
        REAROUTBOARDCOORDSMMLabel       matlab.ui.control.Label
        FrontInboardTable               matlab.ui.control.Table
        FrontOutboardTable              matlab.ui.control.Table
        RearInboardTable                matlab.ui.control.Table
        RearOutboardTable               matlab.ui.control.Table
        FrontAArmVis                    matlab.ui.control.UIAxes
        RearAArmVis                     matlab.ui.control.UIAxes
        LoadsandForcesTab               matlab.ui.container.Tab
        SeeNotestabforinstructionsLabel  matlab.ui.control.Label
        VehicleInputLabel               matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        RRLabel                         matlab.ui.control.Label
        RLLabel                         matlab.ui.control.Label
        FRLabel                         matlab.ui.control.Label
        FLLabel                         matlab.ui.control.Label
        RRLOADSLabel                    matlab.ui.control.Label
        RLLOADSLabel                    matlab.ui.control.Label
        FRLOADSLabel                    matlab.ui.control.Label
        FLLOADSLabel                    matlab.ui.control.Label
        ClEditFieldLabel                matlab.ui.control.Label
        FLLoadsTable                    matlab.ui.control.Table
        FRLoadsTable                    matlab.ui.control.Table
        RLLoadsTable                    matlab.ui.control.Table
        RRLoadsTable                    matlab.ui.control.Table
        FLAArmForceTable                matlab.ui.control.Table
        FRAArmForceTable                matlab.ui.control.Table
        RLAArmForceTable                matlab.ui.control.Table
        RRAArmForceTable                matlab.ui.control.Table
        AccelInputTable                 matlab.ui.control.Table
        CustomForceInputTable           matlab.ui.control.Table
        TLLTDEditField                  matlab.ui.control.NumericEditField
        TLLTDEditFieldLabel             matlab.ui.control.Label
        CGHeightmEditField              matlab.ui.control.NumericEditField
        CGHeightmEditFieldLabel         matlab.ui.control.Label
        WheelbasemEditField             matlab.ui.control.NumericEditField
        WheelbasemEditFieldLabel        matlab.ui.control.Label
        TrackmEditField                 matlab.ui.control.NumericEditField
        TrackmEditFieldLabel            matlab.ui.control.Label
        TotalMasskgEditField            matlab.ui.control.NumericEditField
        ClEditField                     matlab.ui.control.NumericEditField
        CalculateButton                 matlab.ui.control.Button
        TotalMasskgEditFieldLabel       matlab.ui.control.Label
        FailureAnalylsisTab             matlab.ui.container.Tab
        REARTIERODEditField             matlab.ui.control.NumericEditField
        REARTIERODSlider                matlab.ui.control.Slider
        REARTIERODSliderLabel           matlab.ui.control.Label
        REARPUSHRODEditField            matlab.ui.control.NumericEditField
        REARPUSHRODSlider               matlab.ui.control.Slider
        REARPUSHRODSliderLabel          matlab.ui.control.Label
        REARLOWERAFTEditField           matlab.ui.control.NumericEditField
        REARLOWERAFTSlider              matlab.ui.control.Slider
        REARLOWERAFTSliderLabel         matlab.ui.control.Label
        REARLOWERFOREEditField          matlab.ui.control.NumericEditField
        REARLOWERFORESlider             matlab.ui.control.Slider
        REARLOWERFORESliderLabel        matlab.ui.control.Label
        REARUPPERAFTEditField           matlab.ui.control.NumericEditField
        REARUPPERAFTSlider              matlab.ui.control.Slider
        REARUPPERAFTSliderLabel         matlab.ui.control.Label
        REARUPPERFOREEditField          matlab.ui.control.NumericEditField
        REARUPPERFORESlider             matlab.ui.control.Slider
        REARUPPERFORESliderLabel        matlab.ui.control.Label
        FRONTTIERODEditField            matlab.ui.control.NumericEditField
        FRONTTIERODSlider               matlab.ui.control.Slider
        FRONTTIERODSliderLabel          matlab.ui.control.Label
        FRONTPUSHRODEditField           matlab.ui.control.NumericEditField
        FRONTPUSHRODSlider              matlab.ui.control.Slider
        FRONTPUSHRODSliderLabel         matlab.ui.control.Label
        FRONTLOWERAFTEditField          matlab.ui.control.NumericEditField
        FRONTLOWERAFTSlider             matlab.ui.control.Slider
        FRONTLOWERAFTSliderLabel        matlab.ui.control.Label
        FRONTLOWERFOREEditField         matlab.ui.control.NumericEditField
        FRONTLOWERFORESlider            matlab.ui.control.Slider
        FRONTLOWERFORESliderLabel       matlab.ui.control.Label
        FRONTUPPERAFTEditField          matlab.ui.control.NumericEditField
        FRONTUPPERAFTSlider             matlab.ui.control.Slider
        FRONTUPPERAFTSliderLabel        matlab.ui.control.Label
        FRONTUPPERFOREEditField         matlab.ui.control.NumericEditField
        FRONTUPPERFORESlider            matlab.ui.control.Slider
        FRONTUPPERFORESliderLabel       matlab.ui.control.Label
        TubeSelectionLabel              matlab.ui.control.Label
        TubeSizeTable4130SteelLabel     matlab.ui.control.Label
        RRLabel_2                       matlab.ui.control.Label
        RLLabel_2                       matlab.ui.control.Label
        FRLabel_2                       matlab.ui.control.Label
        FLLabel_2                       matlab.ui.control.Label
        Hyperlink_14                    matlab.ui.control.Hyperlink
        Hyperlink_13                    matlab.ui.control.Hyperlink
        Hyperlink_12                    matlab.ui.control.Hyperlink
        Hyperlink_11                    matlab.ui.control.Hyperlink
        Hyperlink_10                    matlab.ui.control.Hyperlink
        Hyperlink_9                     matlab.ui.control.Hyperlink
        Hyperlink_8                     matlab.ui.control.Hyperlink
        Hyperlink_7                     matlab.ui.control.Hyperlink
        Hyperlink_6                     matlab.ui.control.Hyperlink
        Hyperlink_5                     matlab.ui.control.Hyperlink
        Hyperlink_4                     matlab.ui.control.Hyperlink
        Hyperlink_3                     matlab.ui.control.Hyperlink
        Hyperlink_2                     matlab.ui.control.Hyperlink
        Hyperlink                       matlab.ui.control.Hyperlink
        TubeTable                       matlab.ui.control.Table
        FLAArmSFTable                   matlab.ui.control.Table
        FRAArmSFTable                   matlab.ui.control.Table
        RLAArmSFTable                   matlab.ui.control.Table
        RRAArmSFTable                   matlab.ui.control.Table
        NotesTab                        matlab.ui.container.Tab
        SimplificationsTextArea_2       matlab.ui.control.TextArea
        SimplificationsTextArea_2Label  matlab.ui.control.Label
        HewhopersistentlylaiduponminekeyboardLabel  matlab.ui.control.Label
        Image2                          matlab.ui.control.Image
        LoadsandForcesTextArea          matlab.ui.control.TextArea
        LoadsandForcesTextAreaLabel     matlab.ui.control.Label
    end


    properties (Access = private)

        % Conversion scales
        mm2m = 0.001;
        m2mm = 1000;
        g = 9.81;
        m_s2mph = 2.237;
        mph2m_s = 1/2.237;
        in2m = 0.0254;

        % Default pick-up coords. for HT08
        FrontInboard = [102.857,   -265.562,   -285;
                        -245.283,  -247.422,   -296.648;
                        110,       -186.312,   -115;
                        -177.051,  -196.511,   -144.279;
                        -219.244,  -178.214,   -515.125;
                        76,        -182.88,    -141] .* 0.001;

        FrontOutboard = [-5.823,   -528.913,   -287.976;
                         -5.823,   -528.913,   -287.976;
                         1.398,    -553.661,   -99.994;
                         1.398,    -553.661,   -99.994;
                         -45.431,  -464.716,   -317.706;
                         76,       -552.779,   -129;
                         0,        -600,        0] .* 0.001;

        RearInboard = [-1380.224,   -286.29,    -225.015;
                       -1575,       -255,       -262;
                       -1328.191,   -333,       -114.555;
                       -1571.989,   -204.693,   -107.055;
                       -1331.337,   -223.854,   -397.858;
                       -1600.5,     -203,       -133] .* 0.001;

        RearOutboard = [-1533,      -508,       -297;
                        -1533,      -508,       -297;
                        -1488,      -522,       -108;
                        -1488,      -522,       -108;
                        -1477.071,  -488.745,   -131.616;
                        -1615,      -531,       -141
                        -1535,      -600,       0] .* 0.001;

        % A Arm coords. --> Structures
        FRInboard = struct('A',  zeros(1, 3), 'B',  zeros(1, 3), 'C',  zeros(1, 3), 'D',  zeros(1, 3), 'E',  zeros(1, 3), 'F',  zeros(1, 3));
        FROutboard = struct('A',  zeros(1, 3), 'B',  zeros(1, 3), 'C',  zeros(1, 3), 'D',  zeros(1, 3), 'E',  zeros(1, 3), 'F',  zeros(1, 3), 'CP', zeros(1, 3));

        RRInboard = struct('A',  zeros(1, 3), 'B',  zeros(1, 3), 'C',  zeros(1, 3), 'D',  zeros(1, 3), 'E',  zeros(1, 3), 'F',  zeros(1, 3));
        RROutboard = struct('A',  zeros(1, 3), 'B',  zeros(1, 3), 'C',  zeros(1, 3), 'D',  zeros(1, 3), 'E',  zeros(1, 3), 'F',  zeros(1, 3), 'CP', zeros(1, 3));
        
        % Length per arm, front
        FrontAArmLength = struct('A',  zeros(1, 1), 'B',  zeros(1, 1), 'C',  zeros(1, 1), 'D',  zeros(1, 1), 'E',  zeros(1, 1), 'F',  zeros(1, 1));

        % Force direction per arm, front
        FrontAArmForceDir = struct('A',  zeros(1, 3), 'B',  zeros(1, 3), 'C',  zeros(1, 3), 'D',  zeros(1, 3), 'E',  zeros(1, 3), 'F',  zeros(1, 3));
        
        % Arm from O (axis origin, 0, 0, 0) to arm, refer to uploaded
        % notes
        FrontAArmR = struct('A',  zeros(1, 3), 'B',  zeros(1, 3), 'C',  zeros(1, 3), 'D',  zeros(1, 3), 'E',  zeros(1, 3), 'F',  zeros(1, 3), 'CP', zeros(1, 3));

        % Distance cross force dir (Moment dir), front
        FrontAArmMDir = struct('A',  zeros(1, 3), 'B',  zeros(1, 3), 'C',  zeros(1, 3), 'D',  zeros(1, 3), 'E',  zeros(1, 3), 'F',  zeros(1, 3));

        % Length per arm, rear
        RearAArmLength = struct('A',  zeros(1, 1), 'B',  zeros(1, 1), 'C',  zeros(1, 1), 'D',  zeros(1, 1), 'E',  zeros(1, 1), 'F',  zeros(1, 1));

        % Force direction per arm, rear
        RearAArmForceDir = struct('A',  zeros(1, 3), 'B',  zeros(1, 3), 'C',  zeros(1, 3), 'D',  zeros(1, 3), 'E',  zeros(1, 3), 'F',  zeros(1, 3));
       
        % Arm length from O (axis origin) to arm
        RearAArmR = struct('A',  zeros(1, 3), 'B',  zeros(1, 3), 'C',  zeros(1, 3), 'D',  zeros(1, 3), 'E',  zeros(1, 3), 'F',  zeros(1, 3), 'CP', zeros(1, 3));

        % Distance cross force dir (Moment dir), rear
        RearAArmMDir = struct('A',  zeros(1, 3), 'B',  zeros(1, 3), 'C',  zeros(1, 3), 'D',  zeros(1, 3), 'E',  zeros(1, 3), 'F',  zeros(1, 3));


        % Speed, mph --> m/s
        Speed = [0;                 % Static
                 60;                % Accel
                 40;                % Brake
                 30;                % Corner Entry
                 30;                % Corner SS
                 30;                % Corner Exit
                 15;                % 3G Bump
                 0;                 % Custom Accel
                 0] .* 1/2.237      % Custom Force Placeholder

        % Accel, G --> m/s^2
        Accel = [0,     0,      0;              % Static
                 2,     0,      0;              % Accel
                 -2,    0,      0;              % Brake
                 -1,    1,      0;              % Corner Entry
                 0,     1.5,    0;              % Corner SS
                 1,     1,      0;              % Corner Exit
                 0,     0,      -3;             % 3G Bump
                 0,     0,      0;              % Custom Accel
                 0,     0,      0] .* 9.81      % Custom Force Placeholder


        CustomForce = [0, 0, 0, 0, 0, 0];

        % Create tire and a-arm force structures
        %{

            [A;         =       [UPPER FORE;
             B;                  UPPER AFT;
             C;                  LOWER FORE;
             D;                  LOWER AFT;
             E;                  PUSH ROD;
             F]                  TIE ROD;   ]
        
        %}

        %{ 
            AF, Failure Analysis, UPPER FORE

            [
             Max buckling load;
             Max abs. load;

             Max buckling SF;
             Max abs. load SF;
             Stress SF
            ]

        %}

        %{
            AP, Tube properties from tube type selection, UPPER FORE

            [
             Outer Dia (in);
             Outer Rad (m);
             Inner Dia (in);
             Inner Dia (m);

             Moment of Inertia (m^4);
             Area (m^2);

             Yield Strength (MPa);
             Elastic Modulus (GPa);
            ]
        %}

        % AType = Tube type, UPPER FORE

        FL = struct('FX', zeros(9, 1), 'FY', zeros(9, 1), 'FZ', zeros(9, 1), 'MX', zeros(9, 1), 'MY', zeros(9, 1), 'MZ', zeros(9, 1), 'FZPercent', zeros(9, 1), ...
                    'A',  zeros(9, 1), 'B',  zeros(9, 1), 'C',  zeros(9, 1), 'D',  zeros(9, 1), 'E',  zeros(9, 1), 'F',  zeros(9, 1), ...
                    'AF', zeros(5, 1), 'BF', zeros(5, 1), 'CF', zeros(5, 1), 'DF', zeros(5, 1), 'EF', zeros(5, 1), 'FF', zeros(5, 1), ...
                    'AP', zeros(8, 1), 'BP', zeros(8, 1), 'CP', zeros(8, 1), 'DP', zeros(8, 1), 'EP', zeros(8, 1), 'FP', zeros(8, 1), ...
                    'AType', 'Type 1', 'BType', 'Type 1', 'CType', 'Type 7', 'DType', 'Type 7', 'EType', 'Type 7', 'FType', 'Type 1');

        FR = struct('FX', zeros(9, 1), 'FY', zeros(9, 1), 'FZ', zeros(9, 1), 'MX', zeros(9, 1), 'MY', zeros(9, 1), 'MZ', zeros(9, 1), 'FZPercent', zeros(9, 1), ...
                    'A',  zeros(9, 1), 'B',  zeros(9, 1), 'C',  zeros(9, 1), 'D',  zeros(9, 1), 'E',  zeros(9, 1), 'F',  zeros(9, 1), ...
                    'AF', zeros(5, 1), 'BF', zeros(5, 1), 'CF', zeros(5, 1), 'DF', zeros(5, 1), 'EF', zeros(5, 1), 'FF', zeros(5, 1), ...
                    'AP', zeros(8, 1), 'BP', zeros(8, 1), 'CP', zeros(8, 1), 'DP', zeros(8, 1), 'EP', zeros(8, 1), 'FP', zeros(8, 1), ...
                    'AType', 'Type 1', 'BType', 'Type 1', 'CType', 'Type 7', 'DType', 'Type 7', 'EType', 'Type 7', 'FType', 'Type 1');

        RL = struct('FX', zeros(9, 1), 'FY', zeros(9, 1), 'FZ', zeros(9, 1), 'MX', zeros(9, 1), 'MY', zeros(9, 1), 'MZ', zeros(9, 1), 'FZPercent', zeros(9, 1), ...
                    'A',  zeros(9, 1), 'B',  zeros(9, 1), 'C',  zeros(9, 1), 'D',  zeros(9, 1), 'E',  zeros(9, 1), 'F',  zeros(9, 1), ...
                    'AF', zeros(5, 1), 'BF', zeros(5, 1), 'CF', zeros(5, 1), 'DF', zeros(5, 1), 'EF', zeros(5, 1), 'FF', zeros(5, 1), ...
                    'AP', zeros(8, 1), 'BP', zeros(8, 1), 'CP', zeros(8, 1), 'DP', zeros(8, 1), 'EP', zeros(8, 1), 'FP', zeros(8, 1), ...
                    'AType', 'Type 1', 'BType', 'Type 1', 'CType', 'Type 1', 'DType', 'Type 1', 'EType', 'Type 7', 'FType', 'Type 1');

        RR = struct('FX', zeros(9, 1), 'FY', zeros(9, 1), 'FZ', zeros(9, 1), 'MX', zeros(9, 1), 'MY', zeros(9, 1), 'MZ', zeros(9, 1), 'FZPercent', zeros(9, 1), ...
                    'A',  zeros(9, 1), 'B',  zeros(9, 1), 'C',  zeros(9, 1), 'D',  zeros(9, 1), 'E',  zeros(9, 1), 'F',  zeros(9, 1), ...
                    'AF', zeros(5, 1), 'BF', zeros(5, 1), 'CF', zeros(5, 1), 'DF', zeros(5, 1), 'EF', zeros(5, 1), 'FF', zeros(5, 1), ...
                    'AP', zeros(8, 1), 'BP', zeros(8, 1), 'CP', zeros(8, 1), 'DP', zeros(8, 1), 'EP', zeros(8, 1), 'FP', zeros(8, 1), ...
                    'AType', 'Type 1', 'BType', 'Type 1', 'CType', 'Type 1', 'DType', 'Type 1', 'EType', 'Type 7', 'FType', 'Type 1');
        
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % TODO: ADD MAXIMUM STRAIN CHART
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        totMass = 250; % kg
        track = 1.2; % m
        wb = 1.535; % m
        cg = 0.2; % m
        tlltd = 0.5; % Percent front

        cg2RrAxle = 0.78 % m

        rho = 1.225; % Air density, kg/m^3
        Cd = 1.23; % Coeff. drag
        Cl = 3.71; % Coeff. lift
        cp = 0; % Center of pressure height, m
        cp2RrAxle = 0.8109; % m
        frontArea = 1; % m^2

    end
    
    methods (Access = private)
        
        function results = graphAArm(app)
            cla(app.FrontAArmVis)
            cla(app.RearAArmVis)

            hold(app.FrontAArmVis, "on")
            grid(app.FrontAArmVis, "on")
            box(app.FrontAArmVis, "on")

            hold(app.RearAArmVis, "on")
            grid(app.RearAArmVis, "on")
            box(app.RearAArmVis, "on")

            set(app.FrontAArmVis, 'ZDir', 'reverse')
            set(app.FrontAArmVis, 'XDir', 'reverse')
            axis(app.FrontAArmVis, 'equal')

            set(app.RearAArmVis, 'ZDir', 'reverse')
            set(app.RearAArmVis, 'XDir', 'reverse')
            axis(app.RearAArmVis, 'equal')

            % Plot A-Arms

            plot3(app.FrontAArmVis, app.FrontInboard(1,1), app.FrontInboard(1,2), app.FrontInboard(1,3), 'ro')
            plot3(app.FrontAArmVis, app.FrontInboard(2,1), app.FrontInboard(2,2), app.FrontInboard(2,3), 'go')
            plot3(app.FrontAArmVis, app.FrontInboard(3,1), app.FrontInboard(3,2), app.FrontInboard(3,3), 'bo')
            plot3(app.FrontAArmVis, app.FrontInboard(4,1), app.FrontInboard(4,2), app.FrontInboard(4,3), 'mo')
            plot3(app.FrontAArmVis, app.FrontInboard(5,1), app.FrontInboard(5,2), app.FrontInboard(5,3), 'yo')
            plot3(app.FrontAArmVis, app.FrontInboard(6,1), app.FrontInboard(6,2), app.FrontInboard(6,3), 'ko')

            plot3(app.FrontAArmVis, app.FrontOutboard(1,1), app.FrontOutboard(1,2), app.FrontOutboard(1,3), 'r*')
            plot3(app.FrontAArmVis, app.FrontOutboard(2,1), app.FrontOutboard(2,2), app.FrontOutboard(2,3), 'g*')
            plot3(app.FrontAArmVis, app.FrontOutboard(3,1), app.FrontOutboard(3,2), app.FrontOutboard(3,3), 'b*')
            plot3(app.FrontAArmVis, app.FrontOutboard(4,1), app.FrontOutboard(4,2), app.FrontOutboard(4,3), 'm*')
            plot3(app.FrontAArmVis, app.FrontOutboard(5,1), app.FrontOutboard(5,2), app.FrontOutboard(5,3), 'y*')
            plot3(app.FrontAArmVis, app.FrontOutboard(6,1), app.FrontOutboard(6,2), app.FrontOutboard(6,3), 'k*')
            plot3(app.FrontAArmVis, app.FrontOutboard(7,1), app.FrontOutboard(7,2), app.FrontOutboard(7,3), 'r^')

            line(app.FrontAArmVis, [app.FrontInboard(1,1) app.FrontOutboard(1,1)], [app.FrontInboard(1,2) app.FrontOutboard(1,2)], [app.FrontInboard(1,3) app.FrontOutboard(1,3)])
            line(app.FrontAArmVis, [app.FrontInboard(2,1) app.FrontOutboard(2,1)], [app.FrontInboard(2,2) app.FrontOutboard(2,2)], [app.FrontInboard(2,3) app.FrontOutboard(2,3)])
            line(app.FrontAArmVis, [app.FrontInboard(3,1) app.FrontOutboard(3,1)], [app.FrontInboard(3,2) app.FrontOutboard(3,2)], [app.FrontInboard(3,3) app.FrontOutboard(3,3)])
            line(app.FrontAArmVis, [app.FrontInboard(4,1) app.FrontOutboard(4,1)], [app.FrontInboard(4,2) app.FrontOutboard(4,2)], [app.FrontInboard(4,3) app.FrontOutboard(4,3)])
            line(app.FrontAArmVis, [app.FrontInboard(5,1) app.FrontOutboard(5,1)], [app.FrontInboard(5,2) app.FrontOutboard(5,2)], [app.FrontInboard(5,3) app.FrontOutboard(5,3)])
            line(app.FrontAArmVis, [app.FrontInboard(6,1) app.FrontOutboard(6,1)], [app.FrontInboard(6,2) app.FrontOutboard(6,2)], [app.FrontInboard(6,3) app.FrontOutboard(6,3)])

            plot3(app.RearAArmVis, app.RearInboard(1,1), app.RearInboard(1,2), app.RearInboard(1,3), 'ro')
            plot3(app.RearAArmVis, app.RearInboard(2,1), app.RearInboard(2,2), app.RearInboard(2,3), 'go')
            plot3(app.RearAArmVis, app.RearInboard(3,1), app.RearInboard(3,2), app.RearInboard(3,3), 'bo')
            plot3(app.RearAArmVis, app.RearInboard(4,1), app.RearInboard(4,2), app.RearInboard(4,3), 'mo')
            plot3(app.RearAArmVis, app.RearInboard(5,1), app.RearInboard(5,2), app.RearInboard(5,3), 'yo')
            plot3(app.RearAArmVis, app.RearInboard(6,1), app.RearInboard(6,2), app.RearInboard(6,3), 'ko')

            plot3(app.RearAArmVis, app.RearOutboard(1,1), app.RearOutboard(1,2), app.RearOutboard(1,3), 'r*')
            plot3(app.RearAArmVis, app.RearOutboard(2,1), app.RearOutboard(2,2), app.RearOutboard(2,3), 'g*')
            plot3(app.RearAArmVis, app.RearOutboard(3,1), app.RearOutboard(3,2), app.RearOutboard(3,3), 'b*')
            plot3(app.RearAArmVis, app.RearOutboard(4,1), app.RearOutboard(4,2), app.RearOutboard(4,3), 'm*')
            plot3(app.RearAArmVis, app.RearOutboard(5,1), app.RearOutboard(5,2), app.RearOutboard(5,3), 'y*')
            plot3(app.RearAArmVis, app.RearOutboard(6,1), app.RearOutboard(6,2), app.RearOutboard(6,3), 'k*')
            plot3(app.RearAArmVis, app.RearOutboard(7,1), app.RearOutboard(7,2), app.RearOutboard(7,3), 'r^')

            line(app.RearAArmVis, [app.RearInboard(1,1) app.RearOutboard(1,1)], [app.RearInboard(1,2) app.RearOutboard(1,2)], [app.RearInboard(1,3) app.RearOutboard(1,3)])
            line(app.RearAArmVis, [app.RearInboard(2,1) app.RearOutboard(2,1)], [app.RearInboard(2,2) app.RearOutboard(2,2)], [app.RearInboard(2,3) app.RearOutboard(2,3)])
            line(app.RearAArmVis, [app.RearInboard(3,1) app.RearOutboard(3,1)], [app.RearInboard(3,2) app.RearOutboard(3,2)], [app.RearInboard(3,3) app.RearOutboard(3,3)])
            line(app.RearAArmVis, [app.RearInboard(4,1) app.RearOutboard(4,1)], [app.RearInboard(4,2) app.RearOutboard(4,2)], [app.RearInboard(4,3) app.RearOutboard(4,3)])
            line(app.RearAArmVis, [app.RearInboard(5,1) app.RearOutboard(5,1)], [app.RearInboard(5,2) app.RearOutboard(5,2)], [app.RearInboard(5,3) app.RearOutboard(5,3)])
            line(app.RearAArmVis, [app.RearInboard(6,1) app.RearOutboard(6,1)], [app.RearInboard(6,2) app.RearOutboard(6,2)], [app.RearInboard(6,3) app.RearOutboard(6,3)])

            hold(app.FrontAArmVis, "off")
            hold(app.RearAArmVis, "off")
        end

        function calculateLoads(app)

            % Total mass, 1 val. per load case, increment if increasing 
            % number of load cases
            app.totMass = app.totMass .* ones(9, 1);
            
            % Static load, assumes centered CG
            staticFZ = app.totMass .* -app.g ./ 4;

            % Each corner has a set of FZ
            app.FL.FZ = staticFZ;
            app.FR.FZ = staticFZ;
            app.RL.FZ = staticFZ;
            app.RR.FZ = staticFZ;

            % Calc lift
            lift = -0.5 .* app.Cl .* app.rho .* app.Speed .^ 2 .* app.frontArea;

            % Calc weight with lift, totWeight is used to calculate percent
            % FZ
            totWeight = app.totMass .* -app.g + lift;

            % Calc weight with vertical accel.
            totWeight = totWeight + app.Accel(:, 3) .* app.totMass;

            % Calc total lat. load transfer 
            lateralLoadTransfer = app.Accel(:, 2) .* app.totMass .* app.cg ./ app.track;

            % Split tot. lat. load transfer to front and rear
            lateralLoadTransfer_fr = lateralLoadTransfer .* app.tlltd .* -1;
            lateralLoadTransfer_rr = lateralLoadTransfer .* (1 - app.tlltd) .* -1;
    
            % Calc long. load transfer
            longLoadTransfer = app.Accel(:, 1) .* app.totMass .* app.cg ./ app.wb .* -1;

            % Calc wheel normal load, different from totWeight
            normalLoadAZ = app.Accel(:, 3) .* app.totMass;

            app.FL.FZ = app.FL.FZ + lateralLoadTransfer_fr - longLoadTransfer ./ 2 + lift ./ 4 + normalLoadAZ ./ 4;
            app.FR.FZ = app.FR.FZ - lateralLoadTransfer_fr - longLoadTransfer ./ 2 + lift ./ 4 + normalLoadAZ ./ 4;

            app.RL.FZ = app.RL.FZ + lateralLoadTransfer_rr + longLoadTransfer ./ 2 + lift ./ 4 + normalLoadAZ ./ 4;
            app.RR.FZ = app.RR.FZ - lateralLoadTransfer_rr + longLoadTransfer ./ 2 + lift ./ 4 + normalLoadAZ ./ 4;

            % Calculate FZ percent to distribute tot. FY and tot. FX
            app.FL.FZPercent = app.FL.FZ ./ totWeight;
            app.FR.FZPercent = app.FR.FZ ./ totWeight;
            app.RL.FZPercent = app.RL.FZ ./ totWeight;
            app.RR.FZPercent = app.RR.FZ ./ totWeight;

            % Distribute FY
            FYNet = app.totMass .* app.Accel(:, 2);

            app.FL.FY = FYNet .* app.FL.FZPercent;
            app.FR.FY = FYNet .* app.FR.FZPercent;
            app.RL.FY = FYNet .* app.RL.FZPercent;
            app.RR.FY = FYNet .* app.RR.FZPercent;
            
            % Distribute FX
            FXNet = app.totMass .* app.Accel(:, 1);

            app.FL.FX = FXNet .* app.FL.FZPercent;
            app.FR.FX = FXNet .* app.FR.FZPercent;
            app.RL.FX = FXNet .* app.RL.FZPercent;
            app.RR.FX = FXNet .* app.RR.FZPercent;

            % Read custom forces and apply to all four corners
            app.FL.FX(end) = app.CustomForce(1);
            app.FR.FX(end) = app.CustomForce(1);
            app.RL.FX(end) = app.CustomForce(1);
            app.RR.FX(end) = app.CustomForce(1);

            app.FL.FY(end) = app.CustomForce(2);
            app.FR.FY(end) = app.CustomForce(2);
            app.RL.FY(end) = app.CustomForce(2);
            app.RR.FY(end) = app.CustomForce(2);

            app.FL.FZ(end) = app.CustomForce(3);
            app.FR.FZ(end) = app.CustomForce(3);
            app.RL.FZ(end) = app.CustomForce(3);
            app.RR.FZ(end) = app.CustomForce(3);

            app.FL.MX(end) = app.CustomForce(4);
            app.FR.MX(end) = app.CustomForce(4);
            app.RL.MX(end) = app.CustomForce(4);
            app.RR.MX(end) = app.CustomForce(4);

            app.FL.MY(end) = app.CustomForce(5);
            app.FR.MY(end) = app.CustomForce(5);
            app.RL.MY(end) = app.CustomForce(5);
            app.RR.MY(end) = app.CustomForce(5);

            app.FL.MZ(end) = app.CustomForce(6);
            app.FR.MZ(end) = app.CustomForce(6);
            app.RL.MZ(end) = app.CustomForce(6);
            app.RR.MZ(end) = app.CustomForce(6);
        end

        function calculateAArmGeometry(app)

            % Get (X, Y, Z) coords.
            app.FRInboard.A = app.FrontInboard(1, :);
            app.FRInboard.B = app.FrontInboard(2, :);
            app.FRInboard.C = app.FrontInboard(3, :);
            app.FRInboard.D = app.FrontInboard(4, :);
            app.FRInboard.E = app.FrontInboard(5, :);
            app.FRInboard.F = app.FrontInboard(6, :);

            app.FROutboard.A  = app.FrontOutboard(1, :);
            app.FROutboard.B  = app.FrontOutboard(2, :);
            app.FROutboard.C  = app.FrontOutboard(3, :);
            app.FROutboard.D  = app.FrontOutboard(4, :);
            app.FROutboard.E  = app.FrontOutboard(5, :);
            app.FROutboard.F  = app.FrontOutboard(6, :);
            app.FROutboard.CP = app.FrontOutboard(7, :); % Contact Patch

            % A Arm Length Calc
            FrontAArmLengthFN = fieldnames(app.FrontAArmLength);

            for ind = 1:length(FrontAArmLengthFN)
                app.FrontAArmLength.(FrontAArmLengthFN{ind}) = norm(app.FROutboard.(FrontAArmLengthFN{ind}) - app.FRInboard.(FrontAArmLengthFN{ind}));
            end

            % A Arm Force Dir. Calc
            FrontAArmForceDirFN = fieldnames(app.FrontAArmForceDir);

            for ind = 1:length(FrontAArmForceDirFN)
                app.FrontAArmForceDir.(FrontAArmForceDirFN{ind}) = (app.FROutboard.(FrontAArmForceDirFN{ind}) - app.FRInboard.(FrontAArmForceDirFN{ind})) ./ app.FrontAArmLength.(FrontAArmForceDirFN{ind});
            end

            % O --> Arm Calc
            FrontAArmRFN = fieldnames(app.FrontAArmR);

            for ind = 1:length(FrontAArmRFN) - 1
                app.FrontAArmR.(FrontAArmRFN{ind}) = app.FRInboard.(FrontAArmRFN{ind});
            end

            app.FrontAArmR.CP = app.FROutboard.CP;
            
            % A Arm Moment Dir. Calc
            FrontAArmMDirFN = fieldnames(app.FrontAArmMDir);

            for ind = 1:length(FrontAArmMDirFN)
                app.FrontAArmMDir.(FrontAArmMDirFN{ind}) = cross(app.FrontAArmR.(FrontAArmMDirFN{ind}), app.FrontAArmForceDir.(FrontAArmMDirFN{ind}));
            end

            app.RRInboard.A = app.RearInboard(1, :);
            app.RRInboard.B = app.RearInboard(2, :);
            app.RRInboard.C = app.RearInboard(3, :);
            app.RRInboard.D = app.RearInboard(4, :);
            app.RRInboard.E = app.RearInboard(5, :);
            app.RRInboard.F = app.RearInboard(6, :);

            app.RROutboard.A  = app.RearOutboard(1, :);
            app.RROutboard.B  = app.RearOutboard(2, :);
            app.RROutboard.C  = app.RearOutboard(3, :);
            app.RROutboard.D  = app.RearOutboard(4, :);
            app.RROutboard.E  = app.RearOutboard(5, :);
            app.RROutboard.F  = app.RearOutboard(6, :);
            app.RROutboard.CP = app.RearOutboard(7, :);

            RearAArmLengthFN = fieldnames(app.RearAArmLength);

            for ind = 1:length(RearAArmLengthFN)
                app.RearAArmLength.(RearAArmLengthFN{ind}) = norm(app.RROutboard.(RearAArmLengthFN{ind}) - app.RRInboard.(RearAArmLengthFN{ind}));
            end

            RearAArmForceDirFN = fieldnames(app.RearAArmForceDir);

            for ind = 1:length(RearAArmForceDirFN)
                app.RearAArmForceDir.(RearAArmForceDirFN{ind}) = (app.RROutboard.(RearAArmForceDirFN{ind}) - app.RRInboard.(RearAArmForceDirFN{ind})) ./ app.RearAArmLength.(RearAArmForceDirFN{ind});
            end

            RearAArmRFN = fieldnames(app.RearAArmR);

            for ind = 1:length(RearAArmRFN) - 1
                app.RearAArmR.(RearAArmRFN{ind}) = app.RRInboard.(RearAArmRFN{ind});
            end

            app.RearAArmR.CP = app.RROutboard.CP;

            RearAArmMDirFN = fieldnames(app.RearAArmMDir);

            for ind = 1:length(RearAArmMDirFN)
                app.RearAArmMDir.(RearAArmMDirFN{ind}) = cross(app.RearAArmR.(RearAArmMDirFN{ind}), app.RearAArmForceDir.(RearAArmMDirFN{ind}));
            end

        end

        function calculateAArmForces(app)
            % Create vectors for MX, MY, MZ
            FLMtire = zeros(9, 3);
            FRMtire = zeros(9, 3);
            RLMtire = zeros(9, 3);
            RRMtire = zeros(9, 3);

            % Extract loads and calculate moments
            for row = 1:9
                FLforce = [app.FL.FX(row, :), app.FL.FY(row, :), app.FL.FZ(row, :)];
                FLMtire(row, :) = cross(app.FrontAArmR.CP, FLforce);

                FrontRightCP = app.FrontAArmR.CP;
                FrontRightCP(:, 2) = -(FrontRightCP(:, 2));
                FRforce = [app.FR.FX(row, :), app.FR.FY(row, :), app.FR.FZ(row, :)];
                FRMtire(row, :) = cross(FrontRightCP, FRforce);

                RLforce = [app.RL.FX(row, :), app.RL.FY(row, :), app.RL.FZ(row, :)];
                RLMtire(row, :) = cross(app.RearAArmR.CP, RLforce);   

                RearRightCP = app.RearAArmR.CP;
                RearRightCP(:, 2) = -(RearRightCP(:, 2));
                RRforce = [app.RR.FX(row, :), app.RR.FY(row, :), app.RR.FZ(row, :)];
                RRMtire(row, :) = cross(RearRightCP, RRforce);   
            end

            % Ax = b, create A per corner
            FrontLeftA = [app.FrontAArmForceDir.A(1) app.FrontAArmForceDir.B(1) app.FrontAArmForceDir.C(1) app.FrontAArmForceDir.D(1) app.FrontAArmForceDir.E(1) app.FrontAArmForceDir.F(1);
                          app.FrontAArmForceDir.A(2) app.FrontAArmForceDir.B(2) app.FrontAArmForceDir.C(2) app.FrontAArmForceDir.D(2) app.FrontAArmForceDir.E(2) app.FrontAArmForceDir.F(2);
                          app.FrontAArmForceDir.A(3) app.FrontAArmForceDir.B(3) app.FrontAArmForceDir.C(3) app.FrontAArmForceDir.D(3) app.FrontAArmForceDir.E(3) app.FrontAArmForceDir.F(3);
                          app.FrontAArmMDir.A(1)     app.FrontAArmMDir.B(1)     app.FrontAArmMDir.C(1)     app.FrontAArmMDir.D(1)     app.FrontAArmMDir.E(1)     app.FrontAArmMDir.F(1);
                          app.FrontAArmMDir.A(2)     app.FrontAArmMDir.B(2)     app.FrontAArmMDir.C(2)     app.FrontAArmMDir.D(2)     app.FrontAArmMDir.E(2)     app.FrontAArmMDir.F(2);
                          app.FrontAArmMDir.A(3)     app.FrontAArmMDir.B(3)     app.FrontAArmMDir.C(3)     app.FrontAArmMDir.D(3)     app.FrontAArmMDir.E(3)     app.FrontAArmMDir.F(3)];

            FrontRightA = [ app.FrontAArmForceDir.A(1)  app.FrontAArmForceDir.B(1)  app.FrontAArmForceDir.C(1)  app.FrontAArmForceDir.D(1)  app.FrontAArmForceDir.E(1)  app.FrontAArmForceDir.F(1);
                           -app.FrontAArmForceDir.A(2) -app.FrontAArmForceDir.B(2) -app.FrontAArmForceDir.C(2) -app.FrontAArmForceDir.D(2) -app.FrontAArmForceDir.E(2) -app.FrontAArmForceDir.F(2);
                            app.FrontAArmForceDir.A(3)  app.FrontAArmForceDir.B(3)  app.FrontAArmForceDir.C(3)  app.FrontAArmForceDir.D(3)  app.FrontAArmForceDir.E(3)  app.FrontAArmForceDir.F(3);
                           -app.FrontAArmMDir.A(1)     -app.FrontAArmMDir.B(1)     -app.FrontAArmMDir.C(1)     -app.FrontAArmMDir.D(1)     -app.FrontAArmMDir.E(1)     -app.FrontAArmMDir.F(1);
                            app.FrontAArmMDir.A(2)      app.FrontAArmMDir.B(2)      app.FrontAArmMDir.C(2)      app.FrontAArmMDir.D(2)      app.FrontAArmMDir.E(2)      app.FrontAArmMDir.F(2);
                           -app.FrontAArmMDir.A(3)     -app.FrontAArmMDir.B(3)     -app.FrontAArmMDir.C(3)     -app.FrontAArmMDir.D(3)     -app.FrontAArmMDir.E(3)     -app.FrontAArmMDir.F(3)];

            RearLeftA  = [app.RearAArmForceDir.A(1)  app.RearAArmForceDir.B(1)  app.RearAArmForceDir.C(1)  app.RearAArmForceDir.D(1)  app.RearAArmForceDir.E(1)  app.RearAArmForceDir.F(1);
                          app.RearAArmForceDir.A(2)  app.RearAArmForceDir.B(2)  app.RearAArmForceDir.C(2)  app.RearAArmForceDir.D(2)  app.RearAArmForceDir.E(2)  app.RearAArmForceDir.F(2);
                          app.RearAArmForceDir.A(3)  app.RearAArmForceDir.B(3)  app.RearAArmForceDir.C(3)  app.RearAArmForceDir.D(3)  app.RearAArmForceDir.E(3)  app.RearAArmForceDir.F(3);
                          app.RearAArmMDir.A(1)      app.RearAArmMDir.B(1)      app.RearAArmMDir.C(1)      app.RearAArmMDir.D(1)      app.RearAArmMDir.E(1)      app.RearAArmMDir.F(1);
                          app.RearAArmMDir.A(2)      app.RearAArmMDir.B(2)      app.RearAArmMDir.C(2)      app.RearAArmMDir.D(2)      app.RearAArmMDir.E(2)      app.RearAArmMDir.F(2);
                          app.RearAArmMDir.A(3)      app.RearAArmMDir.B(3)      app.RearAArmMDir.C(3)      app.RearAArmMDir.D(3)      app.RearAArmMDir.E(3)      app.RearAArmMDir.F(3)];

            RearRightA  = [ app.RearAArmForceDir.A(1)   app.RearAArmForceDir.B(1)   app.RearAArmForceDir.C(1)   app.RearAArmForceDir.D(1)   app.RearAArmForceDir.E(1)   app.RearAArmForceDir.F(1);
                           -app.RearAArmForceDir.A(2)  -app.RearAArmForceDir.B(2)  -app.RearAArmForceDir.C(2)  -app.RearAArmForceDir.D(2)  -app.RearAArmForceDir.E(2)  -app.RearAArmForceDir.F(2);
                            app.RearAArmForceDir.A(3)   app.RearAArmForceDir.B(3)   app.RearAArmForceDir.C(3)   app.RearAArmForceDir.D(3)   app.RearAArmForceDir.E(3)   app.RearAArmForceDir.F(3);
                           -app.RearAArmMDir.A(1)      -app.RearAArmMDir.B(1)      -app.RearAArmMDir.C(1)      -app.RearAArmMDir.D(1)      -app.RearAArmMDir.E(1)      -app.RearAArmMDir.F(1);
                            app.RearAArmMDir.A(2)       app.RearAArmMDir.B(2)       app.RearAArmMDir.C(2)       app.RearAArmMDir.D(2)       app.RearAArmMDir.E(2)       app.RearAArmMDir.F(2);
                           -app.RearAArmMDir.A(3)      -app.RearAArmMDir.B(3)      -app.RearAArmMDir.C(3)      -app.RearAArmMDir.D(3)      -app.RearAArmMDir.E(3)      -app.RearAArmMDir.F(3)];


            % Ax = b, create b per corner
            FLb = [-(app.FL.FX)';
                   -(app.FL.FY)';
                   -(app.FL.FZ)';
                   -(app.FL.MX' + FLMtire(:, 1)');
                   -(app.FL.MY' + FLMtire(:, 2)');
                   -(app.FL.MZ' + FLMtire(:, 3)')];

            FRb = [-(app.FR.FX)';
                   -(app.FR.FY)';
                   -(app.FR.FZ)';
                   -(app.FR.MX' + FRMtire(:, 1)');
                   -(app.FR.MY' + FRMtire(:, 2)');
                   -(app.FR.MZ' + FRMtire(:, 3)')];

            RLb = [-(app.RL.FX)';
                   -(app.RL.FY)';
                   -(app.RL.FZ)';
                   -(app.RL.MX' + RLMtire(:, 1)');
                   -(app.RL.MY' + RLMtire(:, 2)');
                   -(app.RL.MZ' + RLMtire(:, 3)')];

            RRb = [-(app.RR.FX)';
                   -(app.RR.FY)';
                   -(app.RR.FZ)';
                   -(app.RR.MX' + RRMtire(:, 1)');
                   -(app.RR.MY' + RRMtire(:, 2)');
                   -(app.RR.MZ' + RRMtire(:, 3)')];

            % Ax = b, (x = forces), calculate forces per corner per
            % scenario
            for scenario = 1:size(FLb, 2)
                
                % FL Section
                b = FLb(:, scenario);

                forces = FrontLeftA \ b;

                forces = -forces;

                app.FL.A(scenario) = forces(1);
                app.FL.B(scenario) = forces(2);
                app.FL.C(scenario) = forces(3);
                app.FL.D(scenario) = forces(4);
                app.FL.E(scenario) = forces(5);
                app.FL.F(scenario) = forces(6);

                % FR Section
                b = FRb(:, scenario);

                forces = FrontRightA \ b;

                forces = -forces;

                app.FR.A(scenario) = forces(1);
                app.FR.B(scenario) = forces(2);
                app.FR.C(scenario) = forces(3);
                app.FR.D(scenario) = forces(4);
                app.FR.E(scenario) = forces(5);
                app.FR.F(scenario) = forces(6);

                % RL Section
                b = RLb(:, scenario);

                forces = RearLeftA \ b;

                forces = -forces;

                app.RL.A(scenario) = forces(1);
                app.RL.B(scenario) = forces(2);
                app.RL.C(scenario) = forces(3);
                app.RL.D(scenario) = forces(4);
                app.RL.E(scenario) = forces(5);
                app.RL.F(scenario) = forces(6);

                % RR Section
                b = RRb(:, scenario);

                forces = RearRightA \ b;

                forces = -forces;

                app.RR.A(scenario) = forces(1);
                app.RR.B(scenario) = forces(2);
                app.RR.C(scenario) = forces(3);
                app.RR.D(scenario) = forces(4);
                app.RR.E(scenario) = forces(5);
                app.RR.F(scenario) = forces(6);

            end
        end

        function determineMaxForces(app)

            FFN = {'AF', 'BF', 'CF', 'DF', 'EF', 'FF'};
            Arms = {'A', 'B', 'C', 'D', 'E', 'F'};

            % Find max buckling and abs. loads
            for ind = 1:6

                app.FL.(FFN{ind})(1) = min([0; app.FL.(Arms{ind})]);
                app.FL.(FFN{ind})(2) = max(abs(app.FL.(Arms{ind})));

                app.FR.(FFN{ind})(1) = min([0; app.FR.(Arms{ind})]);
                app.FR.(FFN{ind})(2) = max(abs(app.FR.(Arms{ind})));

                app.RL.(FFN{ind})(1) = min([0; app.RL.(Arms{ind})]);
                app.RL.(FFN{ind})(2) = max(abs(app.RL.(Arms{ind})));

                app.RR.(FFN{ind})(1) = min([0; app.RR.(Arms{ind})]);
                app.RR.(FFN{ind})(2) = max(abs(app.RR.(Arms{ind})));

            end

        end

        function [Do, Di] = getDiameter(app, type)
                switch (type)
                    case 'Type 1'
                        Do = 0.5;  % in
                        Di = 0.444; % in
                    case 'Type 2'
                        Do = 0.5;  % in
                        Di = 0.43; % in
                    case 'Type 3'
                        Do = 0.5;  % in
                        Di = 0.402; % in                        
                    case 'Type 4'
                        Do = 0.5;  % in
                        Di = 0.384; % in                        
                    case 'Type 5'
                        Do = 0.5;  % in
                        Di = 0.37; % in                        
                    case 'Type 6'
                        Do = 0.5;  % in
                        Di = 0.334; % in
                    case 'Type 7'
                        Do = 0.625;  % in
                        Di = 0.569; % in
                    case 'Type 8'
                        Do = 0.625;  % in
                        Di = 0.555; % in
                    case 'Type 9'
                        Do = 0.625;  % in
                        Di = 0.527; % in
                    case 'Type 10'
                        Do = 0.625;  % in
                        Di = 0.509; % in
                    case 'Type 11'
                        Do = 0.625;  % in
                        Di = 0.495; % in
                    case 'Type 12'
                        Do = 0.4375;  % in
                        Di = 0.34; % in
                    case 'Type 13'
                        Do = 0.4375;  % in
                        Di = 0.322; % in
                    case 'Type 14'
                        Do = 0.4375;  % in
                        Di = 0.308; % in
                end
                
        end

        function p = calculateMaterialProp(app, Do, Di)

            p = zeros(8, 1);

            p(1) = Do;  
            p(2) = Do ./ 2 .* app.in2m;
            p(3) = Di;
            p(4) = Di ./ 2 .* app.in2m;

            p(5) = (pi ./ 4) .* (p(2) .^ 4 - p(4) .^ 4);
            p(6) = pi .* (p(2) .^ 2 - p(4) .^ 2);

            % Steel 4130
            p(7) = 435; 
            p(8) = 205;

            %{

            p = [
                 Outer Dia (in);
                 Outer Rad (m);
                 Inner Dia (in);
                 Inner Dia (m);

                 Moment of Inertia (m^4);
                 Area (m^2);

                 Yield Strength (MPa);
                 Elastic Modulus (GPa);
                 ]

            %}

        end

        function setMaterialProp(app)

            TFN = {'AType', 'BType', 'CType', 'DType', 'EType', 'FType'};
            PFN = {'AP', 'BP', 'CP', 'DP', 'EP', 'FP'};

            for ind = 1:6

                % FL
                type = app.FL.(TFN{ind});
                [Do, Di] = getDiameter(app, type);
                p = calculateMaterialProp(app, Do, Di);
                app.FL.(PFN{ind}) = p;

                % FR
                type = app.FR.(TFN{ind});
                [Do, Di] = getDiameter(app, type);
                p = calculateMaterialProp(app, Do, Di);
                app.FR.(PFN{ind}) = p;

                % RL
                type = app.RL.(TFN{ind});
                [Do, Di] = getDiameter(app, type);
                p = calculateMaterialProp(app, Do, Di);
                app.RL.(PFN{ind}) = p;

                % RR
                type = app.RR.(TFN{ind});
                [Do, Di] = getDiameter(app, type);
                p = calculateMaterialProp(app, Do, Di);
                app.RR.(PFN{ind}) = p;
                

            end

        end

        function calculateSF(app)

            FFN = {'AF', 'BF', 'CF', 'DF', 'EF', 'FF'};
            PFN = {'AP', 'BP', 'CP', 'DP', 'EP', 'FP'};
            Arms = {'A', 'B', 'C', 'D', 'E', 'F'};

            % Calculate safety factors
            for ind = 1:6

                critBuckling = (pi .^ 2 .* app.FL.(PFN{ind})(8) .* app.FL.(PFN{ind})(5)) .* 1000000000./ (app.FrontAArmLength.(Arms{ind}) .^ 2);
                app.FL.(FFN{ind})(3) = abs(critBuckling ./ app.FL.(FFN{ind})(1));
                app.FL.(FFN{ind})(4) = abs(critBuckling ./ app.FL.(FFN{ind})(2));
                app.FL.(FFN{ind})(5) = app.FL.(PFN{ind})(7) ./ (app.FL.(FFN{ind})(2) ./ (app.FL.(PFN{ind})(6) .* 1000000));

                critBuckling = (pi .^ 2 .* app.FR.(PFN{ind})(8) .* app.FR.(PFN{ind})(5)) .* 1000000000./ (app.FrontAArmLength.(Arms{ind}) .^ 2);
                app.FR.(FFN{ind})(3) = abs(critBuckling ./ app.FR.(FFN{ind})(1));
                app.FR.(FFN{ind})(4) = abs(critBuckling ./ app.FR.(FFN{ind})(2));
                app.FR.(FFN{ind})(5) = app.FR.(PFN{ind})(7) ./ (app.FR.(FFN{ind})(2) ./ (app.FR.(PFN{ind})(6) .* 1000000));

                critBuckling = (pi .^ 2 .* app.RL.(PFN{ind})(8) .* app.RL.(PFN{ind})(5)) .* 1000000000./ (app.RearAArmLength.(Arms{ind}) .^ 2);
                app.RL.(FFN{ind})(3) = abs(critBuckling ./ app.RL.(FFN{ind})(1));
                app.RL.(FFN{ind})(4) = abs(critBuckling ./ app.RL.(FFN{ind})(2));
                app.RL.(FFN{ind})(5) = app.RL.(PFN{ind})(7) ./ (app.RL.(FFN{ind})(2) ./ (app.RL.(PFN{ind})(6) .* 1000000));

                critBuckling = (pi .^ 2 .* app.RR.(PFN{ind})(8) .* app.RR.(PFN{ind})(5)) .* 1000000000./ (app.RearAArmLength.(Arms{ind}) .^ 2);
                app.RR.(FFN{ind})(3) = abs(critBuckling ./ app.RR.(FFN{ind})(1));
                app.RR.(FFN{ind})(4) = abs(critBuckling ./ app.RR.(FFN{ind})(2));
                app.RR.(FFN{ind})(5) = app.RR.(PFN{ind})(7) ./ (app.RR.(FFN{ind})(2) ./ (app.RR.(PFN{ind})(6) .* 1000000));

            end

        end

        function colorSFTable(app, SFTable)

            % Color table based on safety factor values

            SF = SFTable.DisplayData;

            for row = 3:5

                for col = 1:6

                    if(SF(row, col) > 3)
                        s = uistyle('BackgroundColor','green');
                        addStyle(SFTable, s, 'cell', [row,col]);
                    elseif(SF(row, col) > 2)
                        s = uistyle('BackgroundColor','yellow');
                        addStyle(SFTable, s, 'cell', [row,col]);
                    else
                        s = uistyle('BackgroundColor','red');
                        addStyle(SFTable, s, 'cell', [row,col]);
                    end
                end

            end

        end

        function refreshTables(app)
            app.FLLoadsTable.Data = [app.FL.FX, app.FL.FY, app.FL.FZ, app.FL.MX, app.FL.MY, app.FL.MZ];
            app.FLLoadsTable.RowName = {'Static', 'Accel', 'Brake', 'Corner Entry', 'Corner SS', 'Corner Exit', '3G Bump', 'Custom Accel', 'Custom Force'};

            app.FRLoadsTable.Data = [app.FR.FX, app.FR.FY, app.FR.FZ, app.FR.MX, app.FR.MY, app.FR.MZ];
            app.FRLoadsTable.RowName = {'Static', 'Accel', 'Brake', 'Corner Entry', 'Corner SS', 'Corner Exit', '3G Bump', 'Custom Accel', 'Custom Force'};

            app.RLLoadsTable.Data = [app.RL.FX, app.RL.FY, app.RL.FZ, app.RL.MX, app.RL.MY, app.RL.MZ];
            app.RLLoadsTable.RowName = {'Static', 'Accel', 'Brake', 'Corner Entry', 'Corner SS', 'Corner Exit', '3G Bump', 'Custom Accel', 'Custom Force'};

            app.RRLoadsTable.Data = [app.RR.FX, app.RR.FY, app.RR.FZ, app.RR.MX, app.RR.MY, app.RR.MZ];
            app.RRLoadsTable.RowName = {'Static', 'Accel', 'Brake', 'Corner Entry', 'Corner SS', 'Corner Exit', '3G Bump', 'Custom Accel', 'Custom Force'};

            app.FLAArmForceTable.Data = [app.FL.A, app.FL.B, app.FL.C, app.FL.D, app.FL.E, app.FL.F];
            app.FLAArmForceTable.RowName = {'Static', 'Accel', 'Brake', 'Corner Entry', 'Corner SS', 'Corner Exit', '3G Bump', 'Custom Accel', 'Custom Force'};

            app.FRAArmForceTable.Data = [app.FR.A, app.FR.B, app.FR.C, app.FR.D, app.FR.E, app.FR.F];
            app.FRAArmForceTable.RowName = {'Static', 'Accel', 'Brake', 'Corner Entry', 'Corner SS', 'Corner Exit', '3G Bump', 'Custom Accel', 'Custom Force'};

            app.RLAArmForceTable.Data = [app.RL.A, app.RL.B, app.RL.C, app.RL.D, app.RL.E, app.RL.F];
            app.RLAArmForceTable.RowName = {'Static', 'Accel', 'Brake', 'Corner Entry', 'Corner SS', 'Corner Exit', '3G Bump', 'Custom Accel', 'Custom Force'};

            app.RRAArmForceTable.Data = [app.RR.A, app.RR.B, app.RR.C, app.RR.D, app.RR.E, app.RR.F];
            app.RRAArmForceTable.RowName = {'Static', 'Accel', 'Brake', 'Corner Entry', 'Corner SS', 'Corner Exit', '3G Bump', 'Custom Accel', 'Custom Force'};

            app.FLAArmSFTable.Data = [app.FL.AF, app.FL.BF, app.FL.CF, app.FL.DF, app.FL.EF, app.FL.FF];
            app.FLAArmSFTable.RowName = {'Max Compression', 'Max ABS. Load', 'Buckling SF', 'Buckling SF (Max Load)', 'Stress SF'};

            app.FRAArmSFTable.Data = [app.FR.AF, app.FR.BF, app.FR.CF, app.FR.DF, app.FR.EF, app.FR.FF];
            app.FRAArmSFTable.RowName = {'Max Compression', 'Max ABS. Load', 'Buckling SF', 'Buckling SF (Max Load)', 'Stress SF'};

            app.RLAArmSFTable.Data = [app.RL.AF, app.RL.BF, app.RL.CF, app.RL.DF, app.RL.EF, app.RL.FF];
            app.RLAArmSFTable.RowName = {'Max Compression', 'Max ABS. Load', 'Buckling SF', 'Buckling SF (Max Load)', 'Stress SF'};

            app.RRAArmSFTable.Data = [app.RR.AF, app.RR.BF, app.RR.CF, app.RR.DF, app.RR.EF, app.RR.FF];
            app.RRAArmSFTable.RowName = {'Max Compression', 'Max ABS. Load', 'Buckling SF', 'Buckling SF (Max Load)', 'Stress SF'};

            colorSFTable(app, app.FLAArmSFTable);
            colorSFTable(app, app.FRAArmSFTable);
            colorSFTable(app, app.RLAArmSFTable);
            colorSFTable(app, app.RRAArmSFTable);

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = 'Suspension A Arm Force Solver';

            app.FrontInboardTable.Data = app.FrontInboard .* app.m2mm;
            app.FrontInboardTable.RowName = {'UPPER FORE', 'UPPER AFT', 'LOWER FORE', 'LOWER AFT', 'PUSH ROD', 'TIE ROD'};

            app.FrontOutboardTable.Data = app.FrontOutboard .* app.m2mm;
            app.FrontOutboardTable.RowName = {'UPPER FORE', 'UPPER AFT', 'LOWER FORE', 'LOWER AFT', 'PUSH ROD', 'TIE ROD', 'CONTACT PATCH'};

            app.RearInboardTable.Data = app.RearInboard .* app.m2mm;
            app.RearInboardTable.RowName = {'UPPER FORE', 'UPPER AFT', 'LOWER FORE', 'LOWER AFT', 'PUSH ROD', 'TIE ROD'};

            app.RearOutboardTable.Data = app.RearOutboard .* app.m2mm;
            app.RearOutboardTable.RowName = {'UPPER FORE', 'UPPER AFT', 'LOWER FORE', 'LOWER AFT', 'PUSH ROD', 'TIE ROD', 'CONTACT PATCH'};

            graphAArm(app);

            app.AccelInputTable.Data = [app.Speed(1:end - 1) .* app.m_s2mph, app.Accel(1:end-1, :) ./ app.g];
            app.AccelInputTable.RowName = {'Static', 'Accel', 'Brake', 'Corner Entry', 'Corner SS', 'Corner Exit', '3G Bump', 'Custom Accel'};

            app.CustomForceInputTable.Data = app.CustomForce;
            app.CustomForceInputTable.RowName = 'Custom Force';

            app.FLLoadsTable.Data = [app.FL.FX, app.FL.FY, app.FL.FZ, app.FL.MX, app.FL.MY, app.FL.MZ];
            app.FLLoadsTable.RowName = {'Static', 'Accel', 'Brake', 'Corner Entry', 'Corner SS', 'Corner Exit', '3G Bump', 'Custom Accel', 'Custom Force'};

            app.FRLoadsTable.Data = [app.FR.FX, app.FR.FY, app.FR.FZ, app.FR.MX, app.FR.MY, app.FR.MZ];
            app.FRLoadsTable.RowName = {'Static', 'Accel', 'Brake', 'Corner Entry', 'Corner SS', 'Corner Exit', '3G Bump', 'Custom Accel', 'Custom Force'};

            app.RLLoadsTable.Data = [app.RL.FX, app.RL.FY, app.RL.FZ, app.RL.MX, app.RL.MY, app.RL.MZ];
            app.RLLoadsTable.RowName = {'Static', 'Accel', 'Brake', 'Corner Entry', 'Corner SS', 'Corner Exit', '3G Bump', 'Custom Accel', 'Custom Force'};

            app.RRLoadsTable.Data = [app.RR.FX, app.RR.FY, app.RR.FZ, app.RR.MX, app.RR.MY, app.RR.MZ];
            app.RRLoadsTable.RowName = {'Static', 'Accel', 'Brake', 'Corner Entry', 'Corner SS', 'Corner Exit', '3G Bump', 'Custom Accel', 'Custom Force'};

            app.FLAArmForceTable.Data = [app.FL.A, app.FL.B, app.FL.C, app.FL.D, app.FL.E, app.FL.F];
            app.FLAArmForceTable.RowName = {'Static', 'Accel', 'Brake', 'Corner Entry', 'Corner SS', 'Corner Exit', '3G Bump', 'Custom Accel', 'Custom Force'};

            app.FRAArmForceTable.Data = [app.FR.A, app.FR.B, app.FR.C, app.FR.D, app.FR.E, app.FR.F];
            app.FRAArmForceTable.RowName = {'Static', 'Accel', 'Brake', 'Corner Entry', 'Corner SS', 'Corner Exit', '3G Bump', 'Custom Accel', 'Custom Force'};

            app.RLAArmForceTable.Data = [app.RL.A, app.RL.B, app.RL.C, app.RL.D, app.RL.E, app.RL.F];
            app.RLAArmForceTable.RowName = {'Static', 'Accel', 'Brake', 'Corner Entry', 'Corner SS', 'Corner Exit', '3G Bump', 'Custom Accel', 'Custom Force'};

            app.RRAArmForceTable.Data = [app.RR.A, app.RR.B, app.RR.C, app.RR.D, app.RR.E, app.RR.F];
            app.RRAArmForceTable.RowName = {'Static', 'Accel', 'Brake', 'Corner Entry', 'Corner SS', 'Corner Exit', '3G Bump', 'Custom Accel', 'Custom Force'};

            app.FLAArmSFTable.Data = [app.FL.AF, app.FL.BF, app.FL.CF, app.FL.DF, app.FL.EF, app.FL.FF];
            app.FLAArmSFTable.RowName = {'Max Compression', 'Max ABS. Load', 'Buckling SF', 'Buckling SF (Max Load)', 'Stress SF'};

            app.FRAArmSFTable.Data = [app.FR.AF, app.FR.BF, app.FR.CF, app.FR.DF, app.FR.EF, app.FR.FF];
            app.FRAArmSFTable.RowName = {'Max Compression', 'Max ABS. Load', 'Buckling SF', 'Buckling SF (Max Load)', 'Stress SF'};

            app.RLAArmSFTable.Data = [app.RL.AF, app.RL.BF, app.RL.CF, app.RL.DF, app.RL.EF, app.RL.FF];
            app.RLAArmSFTable.RowName = {'Max Compression', 'Max ABS. Load', 'Buckling SF', 'Buckling SF (Max Load)', 'Stress SF'};

            app.RRAArmSFTable.Data = [app.RR.AF, app.RR.BF, app.RR.CF, app.RR.DF, app.RR.EF, app.RR.FF];
            app.RRAArmSFTable.RowName = {'Max Compression', 'Max ABS. Load', 'Buckling SF', 'Buckling SF (Max Load)', 'Stress SF'};

            TubeProp = zeros(14, 8);

            for i = 1:14
                type = ['Type ' num2str(i)];
                [Do, Di] = getDiameter(app, type);
                p = calculateMaterialProp(app, Do, Di);

                TubeProp(i, :) = p';

            end

            format = {'short', 'short', 'short', 'short', 'long e', 'long e', 'short', 'short'};
            app.TubeTable.ColumnFormat = format;
            app.TubeTable.Data = TubeProp;
            app.TubeTable.RowName = {'Type 1', 'Type 2', 'Type 3', 'Type 4', 'Type 5', 'Type 6', 'Type 7', 'Type 8', 'Type 9', 'Type 10', 'Type 11', 'Type 12', 'Type 13', 'Type 14'};
        end

        % Cell edit callback: FrontInboardTable
        function FrontInboardTableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
            app.FrontInboard(indices(1), indices(2)) = newData.*app.mm2m;

            graphAArm(app);
        end

        % Cell edit callback: FrontOutboardTable
        function FrontOutboardTableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
            app.FrontOutboard(indices(1), indices(2)) = newData.*app.mm2m;

            graphAArm(app);

        end

        % Cell edit callback: RearInboardTable
        function RearInboardTableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
            app.RearInboard(indices(1), indices(2)) = newData.*app.mm2m;

            graphAArm(app);
        end

        % Cell edit callback: RearOutboardTable
        function RearOutboardTableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
            app.RearOutboard(indices(1), indices(2)) = newData.*app.mm2m;

            graphAArm(app);
        end

        % Value changed function: TotalMasskgEditField
        function TotalMasskgEditFieldValueChanged(app, event)
            value = app.TotalMasskgEditField.Value;
            
            app.totMass = value;
        end

        % Value changed function: TrackmEditField
        function TrackmEditFieldValueChanged(app, event)
            value = app.TrackmEditField.Value;
            
            app.track = value;
        end

        % Value changed function: WheelbasemEditField
        function WheelbasemEditFieldValueChanged(app, event)
            value = app.WheelbasemEditField.Value;
            
            app.wb = value;
        end

        % Value changed function: CGHeightmEditField
        function CGHeightmEditFieldValueChanged(app, event)
            value = app.CGHeightmEditField.Value;
            
            app.cg = value;
        end

        % Value changed function: TLLTDEditField
        function TLLTDEditFieldValueChanged(app, event)
            value = app.TLLTDEditField.Value;
            
            app.tlltd = value ./ 100;
        end

        % Cell edit callback: AccelInputTable
        function AccelInputTableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
            row = indices(1);
            col = indices(2);

            if (col == 1)
                app.Speed(row, col) = newData .* app.mph2m_s;
            else
                app.Accel(row, col - 1) = newData .* app.g;
            end

        end

        % Value changed function: ClEditField
        function ClEditFieldValueChanged(app, event)
            value = app.ClEditField.Value;
            
            app.Cl = value;
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            calculateLoads(app);
            calculateAArmGeometry(app);
            calculateAArmForces(app);
            determineMaxForces(app);
            setMaterialProp(app);
            calculateSF(app);
            refreshTables(app);
        end

        % Cell edit callback: CustomForceInputTable
        function CustomForceInputTableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;

            col = indices(2);
            app.CustomForce(col) = newData;

            
        end

        % Value changed function: FRONTUPPERFOREDropDown
        function FRONTUPPERFOREDropDownValueChanged(app, event)
            value = app.FRONTUPPERFOREDropDown.Value;
            
            app.FL.AType = value;
            app.FR.AType = value;

            setMaterialProp(app);
            calculateSF(app);
            refreshTables(app);
        end

        % Value changed function: FRONTUPPERAFTDropDown
        function FRONTUPPERAFTDropDownValueChanged(app, event)
            value = app.FRONTUPPERAFTDropDown.Value;
            
            app.FL.BType = value;
            app.FR.BType = value;

            setMaterialProp(app);
            calculateSF(app);
            refreshTables(app);
        end

        % Value changed function: FRONTLOWERFOREDropDown
        function FRONTLOWERFOREDropDownValueChanged(app, event)
            value = app.FRONTLOWERFOREDropDown.Value;
            
            app.FL.CType = value;
            app.FR.CType = value;

            setMaterialProp(app);
            calculateSF(app);
            refreshTables(app);
        end

        % Value changed function: FRONTLOWERAFTDropDown
        function FRONTLOWERAFTDropDownValueChanged(app, event)
            value = app.FRONTLOWERAFTDropDown.Value;
            
            app.FL.DType = value;
            app.FR.DType = value;

            setMaterialProp(app);
            calculateSF(app);
            refreshTables(app);
        end

        % Value changed function: FRONTPUSHRODDropDown
        function FRONTPUSHRODDropDownValueChanged(app, event)
            value = app.FRONTPUSHRODDropDown.Value;
            
            app.FL.EType = value;
            app.FR.EType = value;

            setMaterialProp(app);
            calculateSF(app);
            refreshTables(app);
        end

        % Value changed function: FRONTTIERODDropDown
        function FRONTTIERODDropDownValueChanged(app, event)
            value = app.FRONTTIERODDropDown.Value;
            
            app.FL.FType = value;
            app.FR.FType = value;

            setMaterialProp(app);
            calculateSF(app);
            refreshTables(app);
        end

        % Value changed function: REARUPPERFOREDropDown
        function REARUPPERFOREDropDownValueChanged(app, event)
            value = app.REARUPPERFOREDropDown.Value;
            
            app.RL.AType = value;
            app.RR.AType = value;

            setMaterialProp(app);
            calculateSF(app);
            refreshTables(app);
        end

        % Value changed function: REARUPPERAFTDropDown
        function REARUPPERAFTDropDownValueChanged(app, event)
            value = app.REARUPPERAFTDropDown.Value;
            
            app.RL.BType = value;
            app.RR.BType = value;

            setMaterialProp(app);
            calculateSF(app);
            refreshTables(app);
        end

        % Value changed function: REARLOWERFOREDropDown
        function REARLOWERFOREDropDownValueChanged(app, event)
            value = app.REARLOWERFOREDropDown.Value;
            
            app.RL.CType = value;
            app.RR.CType = value;

            setMaterialProp(app);
            calculateSF(app);
            refreshTables(app);
        end

        % Value changed function: REARLOWERAFTDropDown
        function REARLOWERAFTDropDownValueChanged(app, event)
            value = app.REARLOWERAFTDropDown.Value;
            
            app.RL.DType = value;
            app.RR.DType = value;

            setMaterialProp(app);
            calculateSF(app);
            refreshTables(app);
        end

        % Value changed function: REARPUSHRODDropDown
        function REARPUSHRODDropDownValueChanged(app, event)
            value = app.REARPUSHRODDropDown.Value;
            
            app.RL.EType = value;
            app.RR.EType = value;

            setMaterialProp(app);
            calculateSF(app);
            refreshTables(app);
        end

        % Value changed function: REARTIERODDropDown
        function REARTIERODDropDownValueChanged(app, event)
            value = app.REARTIERODDropDown.Value;
            
            app.RL.FType = value;
            app.RR.FType = value;

            setMaterialProp(app);
            calculateSF(app);
            refreshTables(app);
        end

        % Value changed function: FRONTUPPERFORESlider
        function FRONTUPPERFORESliderValueChanged(app, event)
            value = round(app.FRONTUPPERFORESlider.Value);

            app.FL.AType = ['Type ' num2str(value)];
            app.FR.AType = ['Type ' num2str(value)];
            app.FRONTUPPERFOREEditField.Value = value;

            setMaterialProp(app);
            calculateSF(app);
            refreshTables(app);
            
            
        end

        % Value changed function: FRONTUPPERAFTSlider
        function FRONTUPPERAFTSliderValueChanged(app, event)
            value = round(app.FRONTUPPERAFTSlider.Value);
            
            app.FL.BType = ['Type ' num2str(value)];
            app.FR.BType = ['Type ' num2str(value)];
            app.FRONTUPPERAFTEditField.Value = value;

            setMaterialProp(app);
            calculateSF(app);
            refreshTables(app);
        end

        % Value changed function: FRONTLOWERFORESlider
        function FRONTLOWERFORESliderValueChanged(app, event)
            value = round(app.FRONTLOWERFORESlider.Value);
            
            app.FL.CType = ['Type ' num2str(value)];
            app.FR.CType = ['Type ' num2str(value)];
            app.FRONTLOWERFOREEditField.Value = value;

            setMaterialProp(app);
            calculateSF(app);
            refreshTables(app);
        end

        % Value changed function: FRONTLOWERAFTSlider
        function FRONTLOWERAFTSliderValueChanged(app, event)
            value = round(app.FRONTLOWERAFTSlider.Value);
            
            app.FL.DType = ['Type ' num2str(value)];
            app.FR.DType = ['Type ' num2str(value)];
            app.FRONTLOWERAFTEditField.Value = value;

            setMaterialProp(app);
            calculateSF(app);
            refreshTables(app);
        end

        % Value changed function: FRONTPUSHRODSlider
        function FRONTPUSHRODSliderValueChanged(app, event)
            value = round(app.FRONTPUSHRODSlider.Value);
            
            app.FL.EType = ['Type ' num2str(value)];
            app.FR.EType = ['Type ' num2str(value)];
            app.FRONTPUSHRODEditField.Value = value;

            setMaterialProp(app);
            calculateSF(app);
            refreshTables(app);
        end

        % Value changed function: FRONTTIERODSlider
        function FRONTTIERODSliderValueChanged(app, event)
            value = round(app.FRONTTIERODSlider.Value);
            
            app.FL.FType = ['Type ' num2str(value)];
            app.FR.FType = ['Type ' num2str(value)];
            app.FRONTTIERODEditField.Value = value;

            setMaterialProp(app);
            calculateSF(app);
            refreshTables(app);
        end

        % Value changed function: REARUPPERFORESlider
        function REARUPPERFORESliderValueChanged(app, event)
            value = round(app.REARUPPERFORESlider.Value);
            
            app.RL.AType = ['Type ' num2str(value)];
            app.RR.AType = ['Type ' num2str(value)];
            app.REARUPPERFOREEditField.Value = value;

            setMaterialProp(app);
            calculateSF(app);
            refreshTables(app);
        end

        % Value changed function: REARUPPERAFTSlider
        function REARUPPERAFTSliderValueChanged(app, event)
            value = round(app.REARUPPERAFTSlider.Value);
            
            app.RL.BType = ['Type ' num2str(value)];
            app.RR.BType = ['Type ' num2str(value)];
            app.REARUPPERAFTEditField.Value = value;

            setMaterialProp(app);
            calculateSF(app);
            refreshTables(app);
        end

        % Value changed function: REARLOWERFORESlider
        function REARLOWERFORESliderValueChanged(app, event)
            value = round(app.REARLOWERFORESlider.Value);
            
            app.RL.CType = ['Type ' num2str(value)];
            app.RR.CType = ['Type ' num2str(value)];
            app.REARLOWERFOREEditField.Value = value;

            setMaterialProp(app);
            calculateSF(app);
            refreshTables(app);
        end

        % Value changed function: REARLOWERAFTSlider
        function REARLOWERAFTSliderValueChanged(app, event)
            value = round(app.REARLOWERAFTSlider.Value);
            
            app.RL.DType = ['Type ' num2str(value)];
            app.RR.DType = ['Type ' num2str(value)];
            app.REARLOWERAFTEditField.Value = value;

            setMaterialProp(app);
            calculateSF(app);
            refreshTables(app);
        end

        % Value changed function: REARPUSHRODSlider
        function REARPUSHRODSliderValueChanged(app, event)
            value = round(app.REARPUSHRODSlider.Value);
            
            app.RL.EType = ['Type ' num2str(value)];
            app.RR.EType = ['Type ' num2str(value)];
            app.REARPUSHRODEditField.Value = value;

            setMaterialProp(app);
            calculateSF(app);
            refreshTables(app);
        end

        % Value changed function: REARTIERODSlider
        function REARTIERODSliderValueChanged(app, event)
            value = round(app.REARTIERODSlider.Value);
            
            app.RL.FType = ['Type ' num2str(value)];
            app.RR.FType = ['Type ' num2str(value)];
            app.REARTIERODEditField.Value = value;

            setMaterialProp(app);
            calculateSF(app);
            refreshTables(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.InputAArmCoordsSAESYSTEMTab = uitab(app.TabGroup);
            app.InputAArmCoordsSAESYSTEMTab.Title = 'Input A-Arm Coords. (SAE SYSTEM)';
            app.RearAArmVis = uiaxes(app.InputAArmCoordsSAESYSTEMTab);
            title(app.RearAArmVis, 'Rear A Arm Visualizer')
            xlabel(app.RearAArmVis, 'X')
            ylabel(app.RearAArmVis, 'Y')
            zlabel(app.RearAArmVis, 'Z')
            app.FrontAArmVis = uiaxes(app.InputAArmCoordsSAESYSTEMTab);
            title(app.FrontAArmVis, 'Front A Arm Visualizer')
            xlabel(app.FrontAArmVis, 'X')
            ylabel(app.FrontAArmVis, 'Y')
            zlabel(app.FrontAArmVis, 'Z')
            app.RearOutboardTable = uitable(app.InputAArmCoordsSAESYSTEMTab);
            app.RearOutboardTable.ColumnName = {'X'; 'Y'; 'Z'};
            app.RearOutboardTable.RowName = {};
            app.RearOutboardTable.ColumnEditable = true;
            app.RearOutboardTable.CellEditCallback = createCallbackFcn(app, @RearOutboardTableCellEdit, true);
            app.RearInboardTable = uitable(app.InputAArmCoordsSAESYSTEMTab);
            app.RearInboardTable.ColumnName = {'X'; 'Y'; 'Z'};
            app.RearInboardTable.RowName = {};
            app.RearInboardTable.ColumnEditable = true;
            app.RearInboardTable.CellEditCallback = createCallbackFcn(app, @RearInboardTableCellEdit, true);
            app.FrontOutboardTable = uitable(app.InputAArmCoordsSAESYSTEMTab);
            app.FrontOutboardTable.ColumnName = {'X'; 'Y'; 'Z'};
            app.FrontOutboardTable.RowName = {};
            app.FrontOutboardTable.ColumnEditable = true;
            app.FrontOutboardTable.CellEditCallback = createCallbackFcn(app, @FrontOutboardTableCellEdit, true);
            app.FrontInboardTable = uitable(app.InputAArmCoordsSAESYSTEMTab);
            app.FrontInboardTable.ColumnName = {'X'; 'Y'; 'Z'};
            app.FrontInboardTable.RowName = {};
            app.FrontInboardTable.ColumnEditable = true;
            app.FrontInboardTable.CellEditCallback = createCallbackFcn(app, @FrontInboardTableCellEdit, true);
            app.REAROUTBOARDCOORDSMMLabel = uilabel(app.InputAArmCoordsSAESYSTEMTab);
            app.REAROUTBOARDCOORDSMMLabel.Text = 'REAR OUTBOARD COORDS. (MM)';
            app.REARINBOARDCOORDSMMLabel = uilabel(app.InputAArmCoordsSAESYSTEMTab);
            app.REARINBOARDCOORDSMMLabel.Text = 'REAR INBOARD COORDS. (MM)';
            app.FRONTOUTBOARDCOORDSMMLabel = uilabel(app.InputAArmCoordsSAESYSTEMTab);
            app.FRONTOUTBOARDCOORDSMMLabel.Text = 'FRONT OUTBOARD COORDS. (MM)';
            app.FRONTINBOARDCOORDSMMLabel = uilabel(app.InputAArmCoordsSAESYSTEMTab);
            app.FRONTINBOARDCOORDSMMLabel.Text = 'FRONT INBOARD COORDS. (MM)';
            app.ENTERLEFTSIDEVEHICLECOORDSNEGATIVEYCOORDSLabel = uilabel(app.InputAArmCoordsSAESYSTEMTab);
            app.ENTERLEFTSIDEVEHICLECOORDSNEGATIVEYCOORDSLabel.Text = {'ENTER LEFT SIDE VEHICLE COORDS'; 'NEGATIVE Y-COORDS'};
            app.LoadsandForcesTab = uitab(app.TabGroup);
            app.LoadsandForcesTab.Title = 'Loads and Forces';
            app.TotalMasskgEditFieldLabel = uilabel(app.LoadsandForcesTab);
            app.TotalMasskgEditFieldLabel.Text = 'Total Mass (kg)';
            app.CalculateButton = uibutton(app.LoadsandForcesTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.ClEditField = uieditfield(app.LoadsandForcesTab, 'numeric');
            app.ClEditField.ValueChangedFcn = createCallbackFcn(app, @ClEditFieldValueChanged, true);
            app.ClEditField.Value = 3.71;
            app.TotalMasskgEditField = uieditfield(app.LoadsandForcesTab, 'numeric');
            app.TotalMasskgEditField.ValueChangedFcn = createCallbackFcn(app, @TotalMasskgEditFieldValueChanged, true);
            app.TotalMasskgEditField.Value = 250;
            app.TrackmEditFieldLabel = uilabel(app.LoadsandForcesTab);
            app.TrackmEditFieldLabel.Text = 'Track (m)';
            app.TrackmEditField = uieditfield(app.LoadsandForcesTab, 'numeric');
            app.TrackmEditField.ValueChangedFcn = createCallbackFcn(app, @TrackmEditFieldValueChanged, true);
            app.TrackmEditField.Value = 1.2;
            app.WheelbasemEditFieldLabel = uilabel(app.LoadsandForcesTab);
            app.WheelbasemEditFieldLabel.Text = 'Wheelbase (m)';
            app.WheelbasemEditField = uieditfield(app.LoadsandForcesTab, 'numeric');
            app.WheelbasemEditField.ValueChangedFcn = createCallbackFcn(app, @WheelbasemEditFieldValueChanged, true);
            app.WheelbasemEditField.Value = 1.535;
            app.CGHeightmEditFieldLabel = uilabel(app.LoadsandForcesTab);
            app.CGHeightmEditFieldLabel.Text = 'CG Height (m)';
            app.CGHeightmEditField = uieditfield(app.LoadsandForcesTab, 'numeric');
            app.CGHeightmEditField.ValueChangedFcn = createCallbackFcn(app, @CGHeightmEditFieldValueChanged, true);
            app.CGHeightmEditField.Value = 0.2;
            app.TLLTDEditFieldLabel = uilabel(app.LoadsandForcesTab);
            app.TLLTDEditFieldLabel.Text = 'TLLTD (%)';
            app.TLLTDEditField = uieditfield(app.LoadsandForcesTab, 'numeric');
            app.TLLTDEditField.ValueChangedFcn = createCallbackFcn(app, @TLLTDEditFieldValueChanged, true);
            app.TLLTDEditField.Value = 50;
            app.CustomForceInputTable = uitable(app.LoadsandForcesTab);
            app.CustomForceInputTable.ColumnName = {'FX'; 'FY'; 'FZ'; 'MX'; 'MY'; 'MZ'};
            app.CustomForceInputTable.RowName = {'Custom Force'};
            app.CustomForceInputTable.ColumnEditable = true;
            app.CustomForceInputTable.CellEditCallback = createCallbackFcn(app, @CustomForceInputTableCellEdit, true);
            app.AccelInputTable = uitable(app.LoadsandForcesTab);
            app.AccelInputTable.ColumnName = {'Speed (MPH)'; 'Ax (G)'; 'Ay (G)'; 'Az (G)'};
            app.AccelInputTable.RowName = {};
            app.AccelInputTable.ColumnEditable = true;
            app.AccelInputTable.CellEditCallback = createCallbackFcn(app, @AccelInputTableCellEdit, true);
            app.RRAArmForceTable = uitable(app.LoadsandForcesTab);
            app.RRAArmForceTable.ColumnName = {'UPPER FORE'; 'UPPER AFT'; 'LOWER FORE'; 'LOWER AFT'; 'PUSH ROD'; 'TIE ROD'};
            app.RRAArmForceTable.RowName = {};
            app.RLAArmForceTable = uitable(app.LoadsandForcesTab);
            app.RLAArmForceTable.ColumnName = {'UPPER FORE'; 'UPPER AFT'; 'LOWER FORE'; 'LOWER AFT'; 'PUSH ROD'; 'TIE ROD'};
            app.RLAArmForceTable.RowName = {};
            app.FRAArmForceTable = uitable(app.LoadsandForcesTab);
            app.FRAArmForceTable.ColumnName = {'UPPER FORE'; 'UPPER AFT'; 'LOWER FORE'; 'LOWER AFT'; 'PUSH ROD'; 'TIE ROD'};
            app.FRAArmForceTable.RowName = {};
            app.FLAArmForceTable = uitable(app.LoadsandForcesTab);
            app.FLAArmForceTable.ColumnName = {'UPPER FORE'; 'UPPER AFT'; 'LOWER FORE'; 'LOWER AFT'; 'PUSH ROD'; 'TIE ROD'};
            app.FLAArmForceTable.RowName = {};
            app.FLAArmForceTable.Tag = 'Front Left A Arm Forces';
            app.RRLoadsTable = uitable(app.LoadsandForcesTab);
            app.RRLoadsTable.ColumnName = {'FX'; 'FY'; 'FZ'; 'MX'; 'MY'; 'MZ'};
            app.RRLoadsTable.RowName = {};
            app.RLLoadsTable = uitable(app.LoadsandForcesTab);
            app.RLLoadsTable.ColumnName = {'FX'; 'FY'; 'FZ'; 'MX'; 'MY'; 'MZ'};
            app.RLLoadsTable.RowName = {};
            app.FRLoadsTable = uitable(app.LoadsandForcesTab);
            app.FRLoadsTable.ColumnName = {'FX'; 'FY'; 'FZ'; 'MX'; 'MY'; 'MZ'};
            app.FRLoadsTable.RowName = {};
            app.FLLoadsTable = uitable(app.LoadsandForcesTab);
            app.FLLoadsTable.ColumnName = {'FX'; 'FY'; 'FZ'; 'MX'; 'MY'; 'MZ'};
            app.FLLoadsTable.RowName = {};
            app.ClEditFieldLabel = uilabel(app.LoadsandForcesTab);
            app.ClEditFieldLabel.Text = 'Cl';
            app.FLLOADSLabel = uilabel(app.LoadsandForcesTab);
            app.FLLOADSLabel.Text = 'FL LOADS';
            app.FRLOADSLabel = uilabel(app.LoadsandForcesTab);
            app.FRLOADSLabel.Text = 'FR LOADS';
            app.RLLOADSLabel = uilabel(app.LoadsandForcesTab);
            app.RLLOADSLabel.Text = 'RL LOADS';
            app.RRLOADSLabel = uilabel(app.LoadsandForcesTab);
            app.RRLOADSLabel.Text = 'RR LOADS';
            app.FLLabel = uilabel(app.LoadsandForcesTab);
            app.FLLabel.Text = 'FL';
            app.FRLabel = uilabel(app.LoadsandForcesTab);
            app.FRLabel.Text = 'FR';
            app.RLLabel = uilabel(app.LoadsandForcesTab);
            app.RLLabel.Text = 'RL';
            app.RRLabel = uilabel(app.LoadsandForcesTab);
            app.RRLabel.Text = 'RR';
            app.Image = uiimage(app.LoadsandForcesTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'SAE TIRE AXIS SYSTEM.png');
            app.VehicleInputLabel = uilabel(app.LoadsandForcesTab);
            app.VehicleInputLabel.Text = 'Vehicle Input';
            app.SeeNotestabforinstructionsLabel = uilabel(app.LoadsandForcesTab);
            app.SeeNotestabforinstructionsLabel.Text = 'See Notes tab for instructions';
            app.FailureAnalylsisTab = uitab(app.TabGroup);
            app.FailureAnalylsisTab.Title = 'Failure Analylsis';
            app.RRAArmSFTable = uitable(app.FailureAnalylsisTab);
            app.RRAArmSFTable.ColumnName = {'UPPER FORE'; 'UPPER AFT'; 'LOWER FORE'; 'LOWER AFT'; 'PUSH ROD'; 'TIE ROD'};
            app.RRAArmSFTable.RowName = {};
            app.RLAArmSFTable = uitable(app.FailureAnalylsisTab);
            app.RLAArmSFTable.ColumnName = {'UPPER FORE'; 'UPPER AFT'; 'LOWER FORE'; 'LOWER AFT'; 'PUSH ROD'; 'TIE ROD'};
            app.RLAArmSFTable.RowName = {};
            app.FRAArmSFTable = uitable(app.FailureAnalylsisTab);
            app.FRAArmSFTable.ColumnName = {'UPPER FORE'; 'UPPER AFT'; 'LOWER FORE'; 'LOWER AFT'; 'PUSH ROD'; 'TIE ROD'};
            app.FRAArmSFTable.RowName = {};
            app.FLAArmSFTable = uitable(app.FailureAnalylsisTab);
            app.FLAArmSFTable.ColumnName = {'UPPER FORE'; 'UPPER AFT'; 'LOWER FORE'; 'LOWER AFT'; 'PUSH ROD'; 'TIE ROD'};
            app.FLAArmSFTable.RowName = {};
            app.FLAArmSFTable.Tag = 'Front Left A Arm Forces';
            app.TubeTable = uitable(app.FailureAnalylsisTab);
            app.TubeTable.ColumnName = {'D_o (in)'; 'R_o (m)'; 'D_i (in)'; 'R_i (m)'; 'I (m^4)'; 'A (m^2)'; 'Yield Str. (MPa)'; 'E (GPa)'};
            app.TubeTable.RowName = {};
            app.Hyperlink = uihyperlink(app.FailureAnalylsisTab);
            app.Hyperlink.URL = 'https://www.mcmaster.com/89955K479';
            app.Hyperlink.Text = 'Type 1';
            app.Hyperlink_2 = uihyperlink(app.FailureAnalylsisTab);
            app.Hyperlink_2.URL = 'https://www.mcmaster.com/89955K259/';
            app.Hyperlink_2.Text = 'Type 2';
            app.Hyperlink_3 = uihyperlink(app.FailureAnalylsisTab);
            app.Hyperlink_3.URL = 'https://www.mcmaster.com/89955K269';
            app.Hyperlink_3.Text = 'Type 3';
            app.Hyperlink_4 = uihyperlink(app.FailureAnalylsisTab);
            app.Hyperlink_4.URL = 'https://www.mcmaster.com/89955K138';
            app.Hyperlink_4.Text = 'Type 4';
            app.Hyperlink_5 = uihyperlink(app.FailureAnalylsisTab);
            app.Hyperlink_5.URL = 'https://www.mcmaster.com/89955K559';
            app.Hyperlink_5.Text = 'Type 5';
            app.Hyperlink_6 = uihyperlink(app.FailureAnalylsisTab);
            app.Hyperlink_6.URL = 'https://www.mcmaster.com/89955K569';
            app.Hyperlink_6.Text = 'Type 6';
            app.Hyperlink_7 = uihyperlink(app.FailureAnalylsisTab);
            app.Hyperlink_7.URL = 'https://www.mcmaster.com/89955K489';
            app.Hyperlink_7.Text = 'Type 7';
            app.Hyperlink_8 = uihyperlink(app.FailureAnalylsisTab);
            app.Hyperlink_8.URL = 'https://www.mcmaster.com/89955K279';
            app.Hyperlink_8.Text = 'Type 8';
            app.Hyperlink_9 = uihyperlink(app.FailureAnalylsisTab);
            app.Hyperlink_9.URL = 'https://www.mcmaster.com/89955K289';
            app.Hyperlink_9.Text = 'Type 9';
            app.Hyperlink_10 = uihyperlink(app.FailureAnalylsisTab);
            app.Hyperlink_10.URL = 'https://www.mcmaster.com/89955K148';
            app.Hyperlink_10.Text = 'Type 10';
            app.Hyperlink_11 = uihyperlink(app.FailureAnalylsisTab);
            app.Hyperlink_11.URL = 'https://www.mcmaster.com/89955K579';
            app.Hyperlink_11.Text = 'Type 11';
            app.Hyperlink_12 = uihyperlink(app.FailureAnalylsisTab);
            app.Hyperlink_12.URL = 'https://www.mcmaster.com/89955K739';
            app.Hyperlink_12.Text = 'Type 12';
            app.Hyperlink_13 = uihyperlink(app.FailureAnalylsisTab);
            app.Hyperlink_13.URL = 'https://www.mcmaster.com/89955K128';
            app.Hyperlink_13.Text = 'Type 13';
            app.Hyperlink_14 = uihyperlink(app.FailureAnalylsisTab);
            app.Hyperlink_14.URL = 'https://www.mcmaster.com/89955K749';
            app.Hyperlink_14.Text = 'Type 14';
            app.FLLabel_2 = uilabel(app.FailureAnalylsisTab);
            app.FLLabel_2.Text = 'FL';
            app.FRLabel_2 = uilabel(app.FailureAnalylsisTab);
            app.FRLabel_2.Text = 'FR';
            app.RLLabel_2 = uilabel(app.FailureAnalylsisTab);
            app.RLLabel_2.Text = 'RL';
            app.RRLabel_2 = uilabel(app.FailureAnalylsisTab);
            app.RRLabel_2.Text = 'RR';
            app.TubeSizeTable4130SteelLabel = uilabel(app.FailureAnalylsisTab);
            app.TubeSizeTable4130SteelLabel.Text = 'Tube Size Table (4130 Steel)';
            app.TubeSelectionLabel = uilabel(app.FailureAnalylsisTab);
            app.TubeSelectionLabel.Text = 'Tube Selection';
            app.FRONTUPPERFORESliderLabel = uilabel(app.FailureAnalylsisTab);
            app.FRONTUPPERFORESliderLabel.Text = 'FRONT UPPER FORE';
            app.FRONTUPPERFORESlider = uislider(app.FailureAnalylsisTab);
            app.FRONTUPPERFORESlider.Limits = [1 14];
            app.FRONTUPPERFORESlider.ValueChangedFcn = createCallbackFcn(app, @FRONTUPPERFORESliderValueChanged, true);
            app.FRONTUPPERFORESlider.MinorTicks = [];
            app.FRONTUPPERFORESlider.Value = 1;
            app.FRONTUPPERFOREEditField = uieditfield(app.FailureAnalylsisTab, 'numeric');
            app.FRONTUPPERFOREEditField.Editable = 'off';
            app.FRONTUPPERFOREEditField.Value = 1;
            app.FRONTUPPERAFTSliderLabel = uilabel(app.FailureAnalylsisTab);
            app.FRONTUPPERAFTSliderLabel.Text = 'FRONT UPPER AFT';
            app.FRONTUPPERAFTSlider = uislider(app.FailureAnalylsisTab);
            app.FRONTUPPERAFTSlider.Limits = [1 14];
            app.FRONTUPPERAFTSlider.ValueChangedFcn = createCallbackFcn(app, @FRONTUPPERAFTSliderValueChanged, true);
            app.FRONTUPPERAFTSlider.MinorTicks = [];
            app.FRONTUPPERAFTSlider.Value = 1;
            app.FRONTUPPERAFTEditField = uieditfield(app.FailureAnalylsisTab, 'numeric');
            app.FRONTUPPERAFTEditField.Editable = 'off';
            app.FRONTUPPERAFTEditField.Value = 1;
            app.FRONTLOWERFORESliderLabel = uilabel(app.FailureAnalylsisTab);
            app.FRONTLOWERFORESliderLabel.Text = 'FRONT LOWER FORE';
            app.FRONTLOWERFORESlider = uislider(app.FailureAnalylsisTab);
            app.FRONTLOWERFORESlider.Limits = [1 14];
            app.FRONTLOWERFORESlider.ValueChangedFcn = createCallbackFcn(app, @FRONTLOWERFORESliderValueChanged, true);
            app.FRONTLOWERFORESlider.MinorTicks = [];
            app.FRONTLOWERFORESlider.Value = 7;
            app.FRONTLOWERFOREEditField = uieditfield(app.FailureAnalylsisTab, 'numeric');
            app.FRONTLOWERFOREEditField.Editable = 'off';
            app.FRONTLOWERFOREEditField.Value = 7;
            app.FRONTLOWERAFTSliderLabel = uilabel(app.FailureAnalylsisTab);
            app.FRONTLOWERAFTSliderLabel.Text = 'FRONT LOWER AFT';
            app.FRONTLOWERAFTSlider = uislider(app.FailureAnalylsisTab);
            app.FRONTLOWERAFTSlider.Limits = [1 14];
            app.FRONTLOWERAFTSlider.ValueChangedFcn = createCallbackFcn(app, @FRONTLOWERAFTSliderValueChanged, true);
            app.FRONTLOWERAFTSlider.MinorTicks = [];
            app.FRONTLOWERAFTSlider.Value = 7;
            app.FRONTLOWERAFTEditField = uieditfield(app.FailureAnalylsisTab, 'numeric');
            app.FRONTLOWERAFTEditField.Editable = 'off';
            app.FRONTLOWERAFTEditField.Value = 7;
            app.FRONTPUSHRODSliderLabel = uilabel(app.FailureAnalylsisTab);
            app.FRONTPUSHRODSliderLabel.Text = 'FRONT PUSH ROD';
            app.FRONTPUSHRODSlider = uislider(app.FailureAnalylsisTab);
            app.FRONTPUSHRODSlider.Limits = [1 14];
            app.FRONTPUSHRODSlider.ValueChangedFcn = createCallbackFcn(app, @FRONTPUSHRODSliderValueChanged, true);
            app.FRONTPUSHRODSlider.MinorTicks = [];
            app.FRONTPUSHRODSlider.Value = 7;
            app.FRONTPUSHRODEditField = uieditfield(app.FailureAnalylsisTab, 'numeric');
            app.FRONTPUSHRODEditField.Editable = 'off';
            app.FRONTPUSHRODEditField.Value = 7;
            app.FRONTTIERODSliderLabel = uilabel(app.FailureAnalylsisTab);
            app.FRONTTIERODSliderLabel.Text = 'FRONT TIE ROD';
            app.FRONTTIERODSlider = uislider(app.FailureAnalylsisTab);
            app.FRONTTIERODSlider.Limits = [1 14];
            app.FRONTTIERODSlider.ValueChangedFcn = createCallbackFcn(app, @FRONTTIERODSliderValueChanged, true);
            app.FRONTTIERODSlider.MinorTicks = [];
            app.FRONTTIERODSlider.Value = 1;
            app.FRONTTIERODEditField = uieditfield(app.FailureAnalylsisTab, 'numeric');
            app.FRONTTIERODEditField.Editable = 'off';
            app.FRONTTIERODEditField.Value = 1;
            app.REARUPPERFORESliderLabel = uilabel(app.FailureAnalylsisTab);
            app.REARUPPERFORESliderLabel.Text = 'REAR UPPER FORE';
            app.REARUPPERFORESlider = uislider(app.FailureAnalylsisTab);
            app.REARUPPERFORESlider.Limits = [1 14];
            app.REARUPPERFORESlider.ValueChangedFcn = createCallbackFcn(app, @REARUPPERFORESliderValueChanged, true);
            app.REARUPPERFORESlider.MinorTicks = [];
            app.REARUPPERFORESlider.Value = 1;
            app.REARUPPERFOREEditField = uieditfield(app.FailureAnalylsisTab, 'numeric');
            app.REARUPPERFOREEditField.Editable = 'off';
            app.REARUPPERFOREEditField.Value = 1;
            app.REARUPPERAFTSliderLabel = uilabel(app.FailureAnalylsisTab);
            app.REARUPPERAFTSliderLabel.Text = 'REAR UPPER AFT';
            app.REARUPPERAFTSlider = uislider(app.FailureAnalylsisTab);
            app.REARUPPERAFTSlider.Limits = [1 14];
            app.REARUPPERAFTSlider.ValueChangedFcn = createCallbackFcn(app, @REARUPPERAFTSliderValueChanged, true);
            app.REARUPPERAFTSlider.MinorTicks = [];
            app.REARUPPERAFTSlider.Value = 1;
            app.REARUPPERAFTEditField = uieditfield(app.FailureAnalylsisTab, 'numeric');
            app.REARUPPERAFTEditField.Editable = 'off';
            app.REARUPPERAFTEditField.Value = 1;
            app.REARLOWERFORESliderLabel = uilabel(app.FailureAnalylsisTab);
            app.REARLOWERFORESliderLabel.Text = 'REAR LOWER FORE';
            app.REARLOWERFORESlider = uislider(app.FailureAnalylsisTab);
            app.REARLOWERFORESlider.Limits = [1 14];
            app.REARLOWERFORESlider.ValueChangedFcn = createCallbackFcn(app, @REARLOWERFORESliderValueChanged, true);
            app.REARLOWERFORESlider.MinorTicks = [];
            app.REARLOWERFORESlider.Value = 1;
            app.REARLOWERFOREEditField = uieditfield(app.FailureAnalylsisTab, 'numeric');
            app.REARLOWERFOREEditField.Editable = 'off';
            app.REARLOWERFOREEditField.Value = 1;
            app.REARLOWERAFTSliderLabel = uilabel(app.FailureAnalylsisTab);
            app.REARLOWERAFTSliderLabel.Text = 'REAR LOWER AFT';
            app.REARLOWERAFTSlider = uislider(app.FailureAnalylsisTab);
            app.REARLOWERAFTSlider.Limits = [1 14];
            app.REARLOWERAFTSlider.ValueChangedFcn = createCallbackFcn(app, @REARLOWERAFTSliderValueChanged, true);
            app.REARLOWERAFTSlider.MinorTicks = [];
            app.REARLOWERAFTSlider.Value = 1;
            app.REARLOWERAFTEditField = uieditfield(app.FailureAnalylsisTab, 'numeric');
            app.REARLOWERAFTEditField.Editable = 'off';
            app.REARLOWERAFTEditField.Value = 1;
            app.REARPUSHRODSliderLabel = uilabel(app.FailureAnalylsisTab);
            app.REARPUSHRODSliderLabel.Text = 'REAR PUSH ROD';
            app.REARPUSHRODSlider = uislider(app.FailureAnalylsisTab);
            app.REARPUSHRODSlider.Limits = [1 14];
            app.REARPUSHRODSlider.ValueChangedFcn = createCallbackFcn(app, @REARPUSHRODSliderValueChanged, true);
            app.REARPUSHRODSlider.MinorTicks = [];
            app.REARPUSHRODSlider.Value = 7;
            app.REARPUSHRODEditField = uieditfield(app.FailureAnalylsisTab, 'numeric');
            app.REARPUSHRODEditField.Editable = 'off';
            app.REARPUSHRODEditField.Value = 7;
            app.REARTIERODSliderLabel = uilabel(app.FailureAnalylsisTab);
            app.REARTIERODSliderLabel.Text = 'REAR TIE ROD';
            app.REARTIERODSlider = uislider(app.FailureAnalylsisTab);
            app.REARTIERODSlider.Limits = [1 14];
            app.REARTIERODSlider.ValueChangedFcn = createCallbackFcn(app, @REARTIERODSliderValueChanged, true);
            app.REARTIERODSlider.MinorTicks = [];
            app.REARTIERODSlider.Value = 1;
            app.REARTIERODEditField = uieditfield(app.FailureAnalylsisTab, 'numeric');
            app.REARTIERODEditField.Editable = 'off';
            app.REARTIERODEditField.Value = 1;
            app.NotesTab = uitab(app.TabGroup);
            app.NotesTab.Title = 'Notes';
            app.LoadsandForcesTextAreaLabel = uilabel(app.NotesTab);
            app.LoadsandForcesTextAreaLabel.Text = 'Loads and Forces';
            app.LoadsandForcesTextArea = uitextarea(app.NotesTab);
            app.LoadsandForcesTextArea.Editable = 'off';
            app.LoadsandForcesTextArea.Value = {'Speed always positive'; ''; 'Ax = Positive accel, negative brake'; ''; 'Ay = Positive to driver''s right, negative to driver''s left'; ''; 'Az = Positive downwards, negative upwards (ex. a bump)'; ''; 'Mx = Overturning Moment'; ''; 'My = Drive Torque'; ''; 'Mz = Aligning Moment'; ''; 'Follows SAE Axis System'; ''; 'Force = Positive tension, negative compression'};
            app.Image2 = uiimage(app.NotesTab);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'Shoutout.jpg');
            app.HewhopersistentlylaiduponminekeyboardLabel = uilabel(app.NotesTab);
            app.HewhopersistentlylaiduponminekeyboardLabel.Text = 'He who persistently laid upon mine keyboard';
            app.SimplificationsTextArea_2Label = uilabel(app.NotesTab);
            app.SimplificationsTextArea_2Label.Text = 'Simplifications';
            app.SimplificationsTextArea_2 = uitextarea(app.NotesTab);
            app.SimplificationsTextArea_2.Editable = 'off';
            app.SimplificationsTextArea_2.Value = {'1. Even mass distribution'; ''; '2. Constant TLLTD (No effect due to Ax and Ay)'; ''; '4. Calculates FZ based on TLLTD and Ay'; ''; '5. Even distribution of Aero downforce'; ''; '6. Constant CL at all speeds'; ''; '7. Calculates FX and FY based on FZ Percent (No SA, SL)'; ''; '9. No MX, MY, MZ based on accel. inputs'; ''; '10. Static A-Arm coords.'; ''; '11. Buckling SF (Max Load) for peace of mind'};
            app.FRONTUPPERFOREDropDownLabel = uilabel(app.UIFigure);
            app.FRONTUPPERFOREDropDownLabel.Enable = 'off';
            app.FRONTUPPERFOREDropDownLabel.Visible = 'off';
            app.FRONTUPPERFOREDropDownLabel.Text = 'FRONT UPPER FORE';
            app.FRONTUPPERFOREDropDown = uidropdown(app.UIFigure);
            app.FRONTUPPERFOREDropDown.Items = {'Type 1', 'Type 2', 'Type 3', 'Type 4', 'Type 5', 'Type 6', 'Type 7', 'Type 8', 'Type 9', 'Type 10', 'Type 11', 'Type 12', 'Type 13', 'Type 14'};
            app.FRONTUPPERFOREDropDown.ValueChangedFcn = createCallbackFcn(app, @FRONTUPPERFOREDropDownValueChanged, true);
            app.FRONTUPPERFOREDropDown.Enable = 'off';
            app.FRONTUPPERFOREDropDown.Visible = 'off';
            app.FRONTUPPERFOREDropDown.Value = 'Type 1';
            app.FRONTUPPERAFTDropDownLabel = uilabel(app.UIFigure);
            app.FRONTUPPERAFTDropDownLabel.Enable = 'off';
            app.FRONTUPPERAFTDropDownLabel.Visible = 'off';
            app.FRONTUPPERAFTDropDownLabel.Text = 'FRONT UPPER AFT';
            app.FRONTUPPERAFTDropDown = uidropdown(app.UIFigure);
            app.FRONTUPPERAFTDropDown.Items = {'Type 1', 'Type 2', 'Type 3', 'Type 4', 'Type 5', 'Type 6', 'Type 7', 'Type 8', 'Type 9', 'Type 10', 'Type 11', 'Type 12', 'Type 13', 'Type 14'};
            app.FRONTUPPERAFTDropDown.ValueChangedFcn = createCallbackFcn(app, @FRONTUPPERAFTDropDownValueChanged, true);
            app.FRONTUPPERAFTDropDown.Enable = 'off';
            app.FRONTUPPERAFTDropDown.Visible = 'off';
            app.FRONTUPPERAFTDropDown.Value = 'Type 1';
            app.FRONTLOWERFOREDropDownLabel = uilabel(app.UIFigure);
            app.FRONTLOWERFOREDropDownLabel.Enable = 'off';
            app.FRONTLOWERFOREDropDownLabel.Visible = 'off';
            app.FRONTLOWERFOREDropDownLabel.Text = 'FRONT LOWER FORE';
            app.FRONTLOWERFOREDropDown = uidropdown(app.UIFigure);
            app.FRONTLOWERFOREDropDown.Items = {'Type 1', 'Type 2', 'Type 3', 'Type 4', 'Type 5', 'Type 6', 'Type 7', 'Type 8', 'Type 9', 'Type 10', 'Type 11', 'Type 12', 'Type 13', 'Type 14'};
            app.FRONTLOWERFOREDropDown.ValueChangedFcn = createCallbackFcn(app, @FRONTLOWERFOREDropDownValueChanged, true);
            app.FRONTLOWERFOREDropDown.Enable = 'off';
            app.FRONTLOWERFOREDropDown.Visible = 'off';
            app.FRONTLOWERFOREDropDown.Value = 'Type 7';
            app.FRONTPUSHRODDropDownLabel = uilabel(app.UIFigure);
            app.FRONTPUSHRODDropDownLabel.Enable = 'off';
            app.FRONTPUSHRODDropDownLabel.Visible = 'off';
            app.FRONTPUSHRODDropDownLabel.Text = 'FRONT PUSH ROD';
            app.FRONTPUSHRODDropDown = uidropdown(app.UIFigure);
            app.FRONTPUSHRODDropDown.Items = {'Type 1', 'Type 2', 'Type 3', 'Type 4', 'Type 5', 'Type 6', 'Type 7', 'Type 8', 'Type 9', 'Type 10', 'Type 11', 'Type 12', 'Type 13', 'Type 14'};
            app.FRONTPUSHRODDropDown.ValueChangedFcn = createCallbackFcn(app, @FRONTPUSHRODDropDownValueChanged, true);
            app.FRONTPUSHRODDropDown.Enable = 'off';
            app.FRONTPUSHRODDropDown.Visible = 'off';
            app.FRONTPUSHRODDropDown.Value = 'Type 7';
            app.FRONTTIERODDropDownLabel = uilabel(app.UIFigure);
            app.FRONTTIERODDropDownLabel.Enable = 'off';
            app.FRONTTIERODDropDownLabel.Visible = 'off';
            app.FRONTTIERODDropDownLabel.Text = 'FRONT TIE ROD';
            app.FRONTTIERODDropDown = uidropdown(app.UIFigure);
            app.FRONTTIERODDropDown.Items = {'Type 1', 'Type 2', 'Type 3', 'Type 4', 'Type 5', 'Type 6', 'Type 7', 'Type 8', 'Type 9', 'Type 10', 'Type 11', 'Type 12', 'Type 13', 'Type 14'};
            app.FRONTTIERODDropDown.ValueChangedFcn = createCallbackFcn(app, @FRONTTIERODDropDownValueChanged, true);
            app.FRONTTIERODDropDown.Enable = 'off';
            app.FRONTTIERODDropDown.Visible = 'off';
            app.FRONTTIERODDropDown.Value = 'Type 1';
            app.FRONTLOWERAFTDropDownLabel = uilabel(app.UIFigure);
            app.FRONTLOWERAFTDropDownLabel.Enable = 'off';
            app.FRONTLOWERAFTDropDownLabel.Visible = 'off';
            app.FRONTLOWERAFTDropDownLabel.Text = 'FRONT LOWER AFT';
            app.FRONTLOWERAFTDropDown = uidropdown(app.UIFigure);
            app.FRONTLOWERAFTDropDown.Items = {'Type 1', 'Type 2', 'Type 3', 'Type 4', 'Type 5', 'Type 6', 'Type 7', 'Type 8', 'Type 9', 'Type 10', 'Type 11', 'Type 12', 'Type 13', 'Type 14'};
            app.FRONTLOWERAFTDropDown.ValueChangedFcn = createCallbackFcn(app, @FRONTLOWERAFTDropDownValueChanged, true);
            app.FRONTLOWERAFTDropDown.Enable = 'off';
            app.FRONTLOWERAFTDropDown.Visible = 'off';
            app.FRONTLOWERAFTDropDown.Value = 'Type 7';
            app.REARTIERODDropDownLabel = uilabel(app.UIFigure);
            app.REARTIERODDropDownLabel.Enable = 'off';
            app.REARTIERODDropDownLabel.Visible = 'off';
            app.REARTIERODDropDownLabel.Text = 'REAR TIE ROD';
            app.REARTIERODDropDown = uidropdown(app.UIFigure);
            app.REARTIERODDropDown.Items = {'Type 1', 'Type 2', 'Type 3', 'Type 4', 'Type 5', 'Type 6', 'Type 7', 'Type 8', 'Type 9', 'Type 10', 'Type 11', 'Type 12', 'Type 13', 'Type 14'};
            app.REARTIERODDropDown.ValueChangedFcn = createCallbackFcn(app, @REARTIERODDropDownValueChanged, true);
            app.REARTIERODDropDown.Enable = 'off';
            app.REARTIERODDropDown.Visible = 'off';
            app.REARTIERODDropDown.Value = 'Type 1';
            app.REARPUSHRODDropDownLabel = uilabel(app.UIFigure);
            app.REARPUSHRODDropDownLabel.Enable = 'off';
            app.REARPUSHRODDropDownLabel.Visible = 'off';
            app.REARPUSHRODDropDownLabel.Text = 'REAR PUSH ROD';
            app.REARPUSHRODDropDown = uidropdown(app.UIFigure);
            app.REARPUSHRODDropDown.Items = {'Type 1', 'Type 2', 'Type 3', 'Type 4', 'Type 5', 'Type 6', 'Type 7', 'Type 8', 'Type 9', 'Type 10', 'Type 11', 'Type 12', 'Type 13', 'Type 14'};
            app.REARPUSHRODDropDown.ValueChangedFcn = createCallbackFcn(app, @REARPUSHRODDropDownValueChanged, true);
            app.REARPUSHRODDropDown.Enable = 'off';
            app.REARPUSHRODDropDown.Visible = 'off';
            app.REARPUSHRODDropDown.Value = 'Type 7';
            app.REARLOWERAFTDropDownLabel = uilabel(app.UIFigure);
            app.REARLOWERAFTDropDownLabel.Enable = 'off';
            app.REARLOWERAFTDropDownLabel.Visible = 'off';
            app.REARLOWERAFTDropDownLabel.Text = 'REAR LOWER AFT';
            app.REARLOWERAFTDropDown = uidropdown(app.UIFigure);
            app.REARLOWERAFTDropDown.Items = {'Type 1', 'Type 2', 'Type 3', 'Type 4', 'Type 5', 'Type 6', 'Type 7', 'Type 8', 'Type 9', 'Type 10', 'Type 11', 'Type 12', 'Type 13', 'Type 14'};
            app.REARLOWERAFTDropDown.ValueChangedFcn = createCallbackFcn(app, @REARLOWERAFTDropDownValueChanged, true);
            app.REARLOWERAFTDropDown.Enable = 'off';
            app.REARLOWERAFTDropDown.Visible = 'off';
            app.REARLOWERAFTDropDown.Value = 'Type 1';
            app.REARUPPERAFTDropDownLabel = uilabel(app.UIFigure);
            app.REARUPPERAFTDropDownLabel.Enable = 'off';
            app.REARUPPERAFTDropDownLabel.Visible = 'off';
            app.REARUPPERAFTDropDownLabel.Text = 'REAR UPPER AFT';
            app.REARUPPERAFTDropDown = uidropdown(app.UIFigure);
            app.REARUPPERAFTDropDown.Items = {'Type 1', 'Type 2', 'Type 3', 'Type 4', 'Type 5', 'Type 6', 'Type 7', 'Type 8', 'Type 9', 'Type 10', 'Type 11', 'Type 12', 'Type 13', 'Type 14'};
            app.REARUPPERAFTDropDown.ValueChangedFcn = createCallbackFcn(app, @REARUPPERAFTDropDownValueChanged, true);
            app.REARUPPERAFTDropDown.Enable = 'off';
            app.REARUPPERAFTDropDown.Visible = 'off';
            app.REARUPPERAFTDropDown.Value = 'Type 1';
            app.REARUPPERFOREDropDownLabel = uilabel(app.UIFigure);
            app.REARUPPERFOREDropDownLabel.Enable = 'off';
            app.REARUPPERFOREDropDownLabel.Visible = 'off';
            app.REARUPPERFOREDropDownLabel.Text = 'REAR UPPER FORE';
            app.REARUPPERFOREDropDown = uidropdown(app.UIFigure);
            app.REARUPPERFOREDropDown.Items = {'Type 1', 'Type 2', 'Type 3', 'Type 4', 'Type 5', 'Type 6', 'Type 7', 'Type 8', 'Type 9', 'Type 10', 'Type 11', 'Type 12', 'Type 13', 'Type 14'};
            app.REARUPPERFOREDropDown.ValueChangedFcn = createCallbackFcn(app, @REARUPPERFOREDropDownValueChanged, true);
            app.REARUPPERFOREDropDown.Enable = 'off';
            app.REARUPPERFOREDropDown.Visible = 'off';
            app.REARUPPERFOREDropDown.Value = 'Type 1';
            app.REARLOWERFOREDropDownLabel = uilabel(app.UIFigure);
            app.REARLOWERFOREDropDownLabel.Enable = 'off';
            app.REARLOWERFOREDropDownLabel.Visible = 'off';
            app.REARLOWERFOREDropDownLabel.Text = 'REAR LOWER FORE';
            app.REARLOWERFOREDropDown = uidropdown(app.UIFigure);
            app.REARLOWERFOREDropDown.Items = {'Type 1', 'Type 2', 'Type 3', 'Type 4', 'Type 5', 'Type 6', 'Type 7', 'Type 8', 'Type 9', 'Type 10', 'Type 11', 'Type 12', 'Type 13', 'Type 14'};
            app.REARLOWERFOREDropDown.ValueChangedFcn = createCallbackFcn(app, @REARLOWERFOREDropDownValueChanged, true);
            app.REARLOWERFOREDropDown.Enable = 'off';
            app.REARLOWERFOREDropDown.Visible = 'off';
            app.REARLOWERFOREDropDown.Value = 'Type 1';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AArmForceSolver

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end